classdef Homepage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        WhattypeofmeasurmentsdoyouhavePanel  matlab.ui.container.Panel
        NonInvasiveButton_2  matlab.ui.control.Button
        WindkesselButton_2   matlab.ui.control.Button
        InvasiveButton_2     matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage            % allows the next page to be selected
        Output              % Stores the output the user wants to obtain (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method = 1;         % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.UIFigure,"center"); % Centers the GUI in the laptop screen
        end

        % Button pushed function: InvasiveButton_2
        function InvasiveButton_2Pushed(app, event)
            app.Method = 1;                                        % Stores the method of analysis
            app.nextpage = whichinputs(app,app.Output,app.Method); % calls the next screen, passing to it the method the user wants
            pause(0.5)                                             % Pauses while the next screen initiates
            delete(app)                                            % closes the current screen
        end

        % Button pushed function: WindkesselButton_2
        function WindkesselButton_2Pushed(app, event)
            app.Method = 3;                                        % Stores the method of analysis
            app.nextpage = whichinputs(app,app.Output,app.Method); % calls the next screen, passing to it the method the user wants
            pause(0.5)                                             % Pauses while the next screen initiates
            delete(app)   
        end

        % Button pushed function: NonInvasiveButton_2
        function NonInvasiveButton_2Pushed(app, event)
            app.Method = 2;                                        % Stores the method of analysis
            app.nextpage = whichinputs(app,app.Output,app.Method); % calls the next screen, passing to it the method the user wants
            pause(0.5)                                             % Pauses while the next screen initiates
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Analytics_img.jpeg');
            app.WhattypeofmeasurmentsdoyouhavePanel = uipanel(app.UIFigure);
            app.WhattypeofmeasurmentsdoyouhavePanel.Title = 'What type of measurments do you have?';
            app.InvasiveButton_2 = uibutton(app.WhattypeofmeasurmentsdoyouhavePanel, 'push');
            app.InvasiveButton_2.ButtonPushedFcn = createCallbackFcn(app, @InvasiveButton_2Pushed, true);
            app.InvasiveButton_2.Text = 'Invasive';
            app.WindkesselButton_2 = uibutton(app.WhattypeofmeasurmentsdoyouhavePanel, 'push');
            app.WindkesselButton_2.ButtonPushedFcn = createCallbackFcn(app, @WindkesselButton_2Pushed, true);
            app.WindkesselButton_2.Text = 'Windkessel';
            app.NonInvasiveButton_2 = uibutton(app.WhattypeofmeasurmentsdoyouhavePanel, 'push');
            app.NonInvasiveButton_2.ButtonPushedFcn = createCallbackFcn(app, @NonInvasiveButton_2Pushed, true);
            app.NonInvasiveButton_2.Text = 'Non-Invasive';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Homepage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end