classdef Inputpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageuploadPanel           matlab.ui.container.Panel
        Samplingfrequency          matlab.ui.control.EditField
        SamplingfrequencyEditFieldLabel  matlab.ui.control.Label
        UploadECGTraceButton_2     matlab.ui.control.Button
        UploadDUltrasoundButton_2  matlab.ui.control.Button
        UploadUUltrasoundButton_2  matlab.ui.control.Button
        FileuploadPanel            matlab.ui.container.Panel
        TextArea_21                matlab.ui.control.TextArea
        TextArea_20                matlab.ui.control.TextArea
        TextArea_19                matlab.ui.control.TextArea
        TextArea_18                matlab.ui.control.TextArea
        TextArea_17                matlab.ui.control.TextArea
        TextArea_16                matlab.ui.control.TextArea
        TextArea_15                matlab.ui.control.TextArea
        TextArea_14                matlab.ui.control.TextArea
        EditField_15               matlab.ui.control.NumericEditField
        TextArea_13                matlab.ui.control.TextArea
        SelectAfileButton          matlab.ui.control.Button
        TextArea_12                matlab.ui.control.TextArea
        TextArea_11                matlab.ui.control.TextArea
        TextArea_10                matlab.ui.control.TextArea
        TextArea_9                 matlab.ui.control.TextArea
        TextArea_8                 matlab.ui.control.TextArea
        TextArea_7                 matlab.ui.control.TextArea
        SelectECGfileButton        matlab.ui.control.Button
        EditField_14               matlab.ui.control.NumericEditField
        OnefileButton              matlab.ui.control.Button
        SelecttimefileButton       matlab.ui.control.Button
        SelectQfileButton          matlab.ui.control.Button
        SelectDfileButton          matlab.ui.control.Button
        SelectUfileButton          matlab.ui.control.Button
        SelectPfileButton          matlab.ui.control.Button
        EditField_13               matlab.ui.control.NumericEditField
        EditField_12               matlab.ui.control.NumericEditField
        EditField_11               matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        TextArea_3                 matlab.ui.control.TextArea
        EditField_9                matlab.ui.control.NumericEditField
        NextButton                 matlab.ui.control.Button
        BackButton                 matlab.ui.control.Button
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage        % allows the next page to be selected
        CallingApp      % Stores the name of the previous app screen
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Onefile         % stores whether all of the data is stored within a single file
        Othersignal     % No longer needed
        samp            % Stores the sampling frequency used to obtain the data (if a time vector is uploaded this is not used)

        % Stores the input file types of the relevant variables
        P_type
        U_type
        D_type
        Q_type
        A_type
        ECG_type
        time_type
        
        % Stores the units of the inputs
        P_Unit
        U_Unit
        D_Unit
        Q_Unit
        A_Unit

        % Once a file and column have been selected these store the
        % variable arrays
        P
        U
        D
        Q
        A
        ECG
        time

        Alldata % tmeporary variable used to hold the data imported from a file
        check = 0 % used to detect if an ultrasound and if so sends to ultrasound page
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, Output, Pvalue, Uvalue, Dvalue, Qvalue, Avalue, ECGvalue, PvalUnit, UvalUnit, DvalUnit, QvalUnit, AvalUnit, Onefile, Othersignal, timevalue, Method)
            movegui(app.UIFigure,"center"); % Centers the GUI
            % Stores the input arguments as app variables
            app.CallingApp = caller;
            app.Output = Output; 
            app.Method = Method;
            app.Onefile = Onefile;
            app.Othersignal = Othersignal;

            app.P_Unit = PvalUnit;
            app.U_Unit = UvalUnit;
            app.D_Unit = DvalUnit;
            app.Q_Unit = QvalUnit;
            app.A_Unit = AvalUnit;

            app.P_type = Pvalue; % Change in syntax only for clarity
            app.U_type = Uvalue;
            app.D_type = Dvalue;
            app.Q_type = Qvalue;
            app.A_type = Avalue;
            app.ECG_type = ECGvalue;
            app.time_type = timevalue;
%%%%%%%%%%% Makes all of the displays components initially invisible %%%%
            app.SelectPfileButton.Visible = 'off';
            app.SelectUfileButton.Visible = "off";
            app.SelectDfileButton.Visible = 'off';
            app.SelectQfileButton.Visible = "off";
            app.SelectAfileButton.Visible = "off";
            app.SelecttimefileButton.Visible = "off";
            app.SelectECGfileButton.Visible = "off";
            app.OnefileButton.Visible = "off";            
            app.EditField_13.Visible = 'off';
            app.EditField_12.Visible = "off";
            app.EditField_11.Visible = "off";
            app.EditField_10.Visible = "off";
            app.EditField_14.Visible = "off";
            app.EditField_15.Visible = "off";
            app.EditField_9.Visible = "off";    
            app.UploadECGTraceButton_2.Visible = "off";
            app.UploadDUltrasoundButton_2.Visible = "off";
            app.UploadUUltrasoundButton_2.Visible = "off";
            
%%%%%%%%%%% Based off of the users inputs this displays the necessary compents, keeping the display simple %%%%%%   
% This section first decides if the inputs are in seperate files or just
% one file before displaying the inputs the user has selected.
% The display has been coded so that a user must select a file before they
% are able to select a column (by making the column edit fields invisible until a file is selected)
            if app.Onefile == 1                     % case where they are in one file
                app.OnefileButton.Visible = "on";                
            else                                        % case where the inputs are in seperate files
                if Pvalue == "No"
                else
                    app.SelectPfileButton.Visible = "on";                  
                end
                if Uvalue == "No"
                elseif Uvalue == "Image"
                    app.UploadUUltrasoundButton_2.Visible = "on";
                else % For any non-image file upload
                    app.SelectUfileButton.Visible = "on";                  
                end    
                if Dvalue == "No"
                elseif Dvalue == "Image"
                    app.UploadDUltrasoundButton_2.Visible = "on";
                else % For any non-image file upload
                    app.SelectDfileButton.Visible = "on";                    
                end      
                if Avalue == "No"             
                else
                    app.SelectAfileButton.Visible = "on";                    
                end   
                if Qvalue == "No"

                else
                    app.SelectQfileButton.Visible = "on";                                  
                end            
                if ECGvalue == "No"
                elseif ECGvalue == "Image"
                    app.UploadECGTraceButton_2 = "on";
                else % For any non-image file upload
                    app.SelectECGfileButton.Visible = "on";                              
                end  
                if timevalue == "No"                
                else
                    app.SelecttimefileButton.Visible = "on";                                               
                end   
            end


        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Initiates the next display       
            % This calulates U if the user inputs Q and A
            if isempty(app.Q) == 0 && isempty(app.A) == 0 && isempty(app.U) == 1
                app.U = app.Q./app.A;
            end
            if isempty(app.U)
                uiwait(msgbox("Please enter a column number or upload a file","Error"))
            end
            if isempty(app.time) == 1 % Checks if there is a time vector uploaded
                % If no
                if isempty(app.samp) == 0 % checks if a sampling frequency has been added
                    % If a sampling freq has been added
                    a = str2double(app.samp); % Converts string input (from edit field) to double
                    app.time = (0:(1/a):((1/a)*(length(app.U)-1)))'; % Creates a time vector from 0 to the length of the signals - 1                  
                    if app.check == 0 % Checks if a ultrasound dicom has been uploaded
                        % if no, progresses to the Pt_new
                        app.nextpage = Pt_new(app,app.Output,app.P,app.U, ...
                        app.D,app.Q,app.A,app.ECG, app.P_type, app.U_type, app.D_type, app.Q_type, app.A_type ...
                       , app.ECG_type, app.P_Unit, app.U_Unit,app.D_Unit, app.Q_Unit,app.A_Unit, app.Onefile, app.Othersignal,app.time_type,app.time, app.Method);
                    else
                        % if an ultrasound has been uploaded passes to the
                        % ultrasound extraction page
                        app.nextpage = Ultrasound_new(app,app.Output,app.P,app.U, ...
                        app.D,app.Q,app.A,app.ECG, app.P_type, app.U_type, app.D_type, app.Q_type, app.A_type ...
                       , app.ECG_type, app.P_Unit, app.U_Unit,app.D_Unit, app.Q_Unit,app.A_Unit, app.Onefile, app.Othersignal,app.time_type,app.time, app.Method);
                    end
                    pause(0.5)      % Pauses while the next screen initiates
                    delete(app)     % Closes the current screen 
                else
                    % If no sampling frequency or time vector exsists prompts the user to include one 
                    % uiwait() stops the GUI progressing until the user has acnowledged the alert
                    uiwait(msgbox("Please enter a sampling frequency or upload a time vector","Error"))
                end
            else
                % If a time vector has been uploaded
                if app.check == 0
                    % Checks if a ultrasound dicom has been uploaded
                    % if no, progresses to the Pt_new
                    app.nextpage = Pt_new(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG, app.P_type, app.U_type, app.D_type, app.Q_type, app.A_type ...
                   , app.ECG_type, app.P_Unit, app.U_Unit,app.D_Unit, app.Q_Unit,app.A_Unit, app.Onefile, app.Othersignal,app.time_type,app.time, app.Method);
                else
                    % if an ultrasound has been uploaded passes to the ultrasound extraction page
                    app.nextpage = Ultrasound_new(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG, app.P_type, app.U_type, app.D_type, app.Q_type, app.A_type ...
                   , app.ECG_type, app.P_Unit, app.U_Unit,app.D_Unit, app.Q_Unit,app.A_Unit, app.Onefile, app.Othersignal,app.time_type,app.time, app.Method);
                end
                    pause(0.5)      % Pauses while the next screen initiates
                    delete(app)     % Closes the current screen 
            end

            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % allows the user to go to the previous display
            app.nextpage = whichinputs(app, app.Output, app.Method); % passes only output and method as these are input variables required for the previous page
            pause(0.5)              % Pauses while the next screen initiates
            delete(app)             % Closes the current screen 
        end

        % Button pushed function: SelectPfileButton
        function SelectPfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_14.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.P = table2array(a);                         % Converts the table into an array
            app.EditField_9.Visible = "on";                 % Makes the column edit field visible to the user
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;          % Stores the column number in which P data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.P = app.Alldata(:,value);       % Selects a column within the uploaded file (one file case)
            else
                app.P = app.P(:,value);             % Selects a column within the uploaded file
            end
            % Converts the P array into Pa if it is not already
            if app.P_Unit == "mm/Hg"
                app.P = app.P * 133.3;
            elseif app.P_Unit == "kPa"
                app.P = app.P * 1000;
            else
            end
        end

        % Button pushed function: SelectUfileButton
        function SelectUfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_15.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.U = table2array(a);                         % Converts the table into an array
            app.EditField_10.Visible = "on";                % Makes the column edit field visible to the user 
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;         % Stores the column number in which U data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.U = app.Alldata(:,value);       % Selects a column within the uploaded file (one file case)
            else            
                app.U = app.U(:, value);            % Selects a column within the uploaded file
            end
            % Converts the U array into m/s if it is not already
            if app.U_Unit == "mm/s"
                app.U = app.U /1000;
            else
            end
        end

        % Button pushed function: SelectDfileButton
        function SelectDfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_16.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.D = table2array(a);                         % Converts the table into an array
            app.EditField_11.Visible = "on";                % Makes the column edit field visible to the user  
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;         % Stores the column number in which D data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.D = app.Alldata(:,value);       % Selects a column within the uploaded file (one file case)
            else            
                app.D = app.D(:,value);             % Selects a column within the uploaded file
            end
            % Converts the D array into m
            if app.D_Unit == "cm"
                app.D = app.D /10;
            elseif app.D_Unit == "mm"
                app.D = app.D / 1000;
            end
        end

        % Button pushed function: SelectQfileButton
        function SelectQfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_18.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.Q = table2array(a);                         % Converts the table into an array
            app.EditField_12.Visible = "on";                % Makes the column edit field visible to the user              
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;         % Stores the column number in which Q data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.Q = app.Alldata(:,value);       % Selects a column within the uploaded file (one file case)
            else            
                app.Q = app.Q(:,value);             % Selects a column within the uploaded file
            end
            % Converts the Q array into m^3/s if it is not already
            if app.Q_Unit == "l/min"
                app.Q = app.Q /60000;
            elseif app.Q_Unit == "ml/min"
                app.Q = app.Q / 60000000;
            end             
        end

        % Button pushed function: SelecttimefileButton
        function SelecttimefileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_19.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.time = table2array(a);                      % Converts the table into an array
            app.EditField_13.Visible = "on";                % Makes the column edit field visible to the user    
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;         % Stores the column number in which time data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.time = app.Alldata(:,value);    % Selects a column within the uploaded file (one file case)
            else            
                app.time = app.time(:,value);       % Selects a column within the uploaded file
            end        
        
        end

        % Button pushed function: OnefileButton
        function OnefileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_17.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)
            app.Alldata = table2array(a);                   % Converts the table into an array

            % Displays the appropreate column edit fields once a user has
            % selected a file
            if app.P_type == "No"
            else
                app.EditField_9.Visible = "on";  
            end
    
            if app.U_type == "No"
            elseif app.U_type == "Image"
                app.UploadUUltrasoundButton_2.Visible = "on";
            else    % For any non-image file upload  
                app.EditField_10.Visible = "on";  
            end
    
            if app.D_type == "No"
            elseif app.D_type == "Image"
                app.UploadDUltrasoundButton_2.Visible = "on";
            else    % For any non-image file upload  
                app.EditField_11.Visible = "on";  
            end    

            if app.A_type == "No"
            else                
                app.EditField_15.Visible = "on";  
            end 

            if app.Q_type == "No"
            else               
                app.EditField_12.Visible = "on";                  
            end         

            if app.ECG_type == "No"
            elseif app.ECG_type == "Image"
                app.UploadECGTraceButton_2 = "on";
            else    % For any non-image file upload             
                app.EditField_14.Visible = "on";            
            end   

            if app.time_type == "No"
            else             
                app.EditField_13.Visible = "on";            
            end   
        
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;         % Stores the column number in which ECG data is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.ECG = app.Alldata(:,value);     % Selects a column within the uploaded file (one file case)
            else            
                app.ECG = app.ECG(:,value);         % Selects a column within the uploaded file
            end 
        end

        % Button pushed function: SelectECGfileButton
        function SelectECGfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_20.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)       
            app.ECG = table2array(a);                       % Converts the table into an array
            app.EditField_14.Visible = "on";                % Makes the column edit field visible to the user            
        end

        % Value changed function: Samplingfrequency
        function SamplingfrequencyValueChanged(app, event)
            app.samp = app.Samplingfrequency.Value; % sets the sampling frequency value
            
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            
            value = app.EditField_15.Value;         % Stores the column number in which A is in
            if app.Onefile == 1                     % Checks if the user selected that all of their data is in one file
                app.A = app.Alldata(:,value);       % Selects a column within the uploaded file (one file case)
            else
                app.A = app.A(:,value);             % Selects a column within the uploaded file
            end

            % Converts the A array into m^2 if it is not already
            if app.A_Unit == "mm^2"
                app.A = app.A /(1*10^(-6));
            elseif app.A_Unit == "cm^2"
                app.A = app.A / 10000;
            else
            end
        end

        % Button pushed function: SelectAfileButton
        function SelectAfileButtonPushed(app, event)
            [file, path] = uigetfile('*.m; *.xlsx; *.asc; *.csv; *.txt; *.rtf; *.dat; *.mat'); % uses UIgetfile to allow the user to select a file
            app.TextArea_17.Value = fullfile(path, file);   % Displays the file name in the apropreate text box
            a = readtable(fullfile(path, file));            % reads the data as a table (important incase the data is in an excel from)       
            app.A = table2array(a);                         % Converts the table into an array
            app.EditField_15.Visible = "on";                % Makes the column edit field visible to the user               
        end

        % Button pushed function: UploadECGTraceButton_2
        function UploadECGTraceButton_2Pushed(app, event)
            [file, path] = uigetfile('*.mat; *.dicom; *.dcm'); % uses UIgetfile to allow the user to select a file
            app.ECG = fullfile(path, file);    % loads the image   
            app.check = 1;
                       
        end

        % Button pushed function: UploadDUltrasoundButton_2
        function UploadDUltrasoundButton_2Pushed(app, event)
            [file, path] = uigetfile('*.mat; *.dicom; *.dcm'); % uses UIgetfile to allow the user to select a file
            app.D = fullfile(path, file);    % loads the image          
            app.check = 1;           
        end

        % Button pushed function: UploadUUltrasoundButton_2
        function UploadUUltrasoundButton_2Pushed(app, event)
            [file, path] = uigetfile('*.mat; *.dicom; *.dcm'); % uses UIgetfile to allow the user to select a file
            app.U = fullfile(path, file);    % loads the image        
            app.check = 1;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FileuploadPanel = uipanel(app.UIFigure);
            app.FileuploadPanel.Title = 'File upload';
            app.EditField_9 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.TextArea_3 = uitextarea(app.FileuploadPanel);
            app.TextArea_3.Value = {'Column'};
            app.EditField_10 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.SelectPfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectPfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPfileButtonPushed, true);
            app.SelectPfileButton.Text = 'Select P file';
            app.SelectUfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectUfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUfileButtonPushed, true);
            app.SelectUfileButton.Text = 'Select U file';
            app.SelectDfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectDfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDfileButtonPushed, true);
            app.SelectDfileButton.Text = 'Select D file';
            app.SelectQfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectQfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectQfileButtonPushed, true);
            app.SelectQfileButton.Text = 'Select Q file';
            app.SelecttimefileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelecttimefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttimefileButtonPushed, true);
            app.SelecttimefileButton.Text = 'Select time file';
            app.OnefileButton = uibutton(app.FileuploadPanel, 'push');
            app.OnefileButton.ButtonPushedFcn = createCallbackFcn(app, @OnefileButtonPushed, true);
            app.OnefileButton.Text = 'Select file';
            app.EditField_14 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.SelectECGfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectECGfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectECGfileButtonPushed, true);
            app.SelectECGfileButton.Text = 'Select ECG file';
            app.TextArea_7 = uitextarea(app.FileuploadPanel);
            app.TextArea_7.Value = {'P '};
            app.TextArea_8 = uitextarea(app.FileuploadPanel);
            app.TextArea_8.Value = {'U'};
            app.TextArea_9 = uitextarea(app.FileuploadPanel);
            app.TextArea_9.Value = {'D'};
            app.TextArea_10 = uitextarea(app.FileuploadPanel);
            app.TextArea_10.Value = {'Q'};
            app.TextArea_11 = uitextarea(app.FileuploadPanel);
            app.TextArea_11.Value = {'time'};
            app.TextArea_12 = uitextarea(app.FileuploadPanel);
            app.TextArea_12.Value = {'ECG'};
            app.SelectAfileButton = uibutton(app.FileuploadPanel, 'push');
            app.SelectAfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAfileButtonPushed, true);
            app.SelectAfileButton.Text = 'Select A file';
            app.TextArea_13 = uitextarea(app.FileuploadPanel);
            app.TextArea_13.Value = {'A'};
            app.EditField_15 = uieditfield(app.FileuploadPanel, 'numeric');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.TextArea_14 = uitextarea(app.FileuploadPanel);
            app.TextArea_15 = uitextarea(app.FileuploadPanel);
            app.TextArea_16 = uitextarea(app.FileuploadPanel);
            app.TextArea_17 = uitextarea(app.FileuploadPanel);
            app.TextArea_18 = uitextarea(app.FileuploadPanel);
            app.TextArea_19 = uitextarea(app.FileuploadPanel);
            app.TextArea_20 = uitextarea(app.FileuploadPanel);
            app.TextArea_21 = uitextarea(app.FileuploadPanel);
            app.TextArea_21.Value = {'Selected filename'};
            app.ImageuploadPanel = uipanel(app.UIFigure);
            app.ImageuploadPanel.Title = 'Image upload';
            app.UploadUUltrasoundButton_2 = uibutton(app.ImageuploadPanel, 'push');
            app.UploadUUltrasoundButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadUUltrasoundButton_2Pushed, true);
            app.UploadUUltrasoundButton_2.Text = 'Upload U Ultrasound';
            app.UploadDUltrasoundButton_2 = uibutton(app.ImageuploadPanel, 'push');
            app.UploadDUltrasoundButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadDUltrasoundButton_2Pushed, true);
            app.UploadDUltrasoundButton_2.Text = 'Upload D Ultrasound';
            app.UploadECGTraceButton_2 = uibutton(app.ImageuploadPanel, 'push');
            app.UploadECGTraceButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadECGTraceButton_2Pushed, true);
            app.UploadECGTraceButton_2.Text = 'Upload ECG Trace';
            app.SamplingfrequencyEditFieldLabel = uilabel(app.ImageuploadPanel);
            app.SamplingfrequencyEditFieldLabel.Text = 'Sampling frequency';
            app.Samplingfrequency = uieditfield(app.ImageuploadPanel, 'text');
            app.Samplingfrequency.ValueChangedFcn = createCallbackFcn(app, @SamplingfrequencyValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inputpage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end