classdef Outputpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WanttotryadifferentCEditField  matlab.ui.control.NumericEditField
        WanttotryadifferentCEditFieldLabel  matlab.ui.control.Label
        SaveCtoworkspaceButton         matlab.ui.control.Button
        CTextArea                      matlab.ui.control.TextArea
        CTextAreaLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PDSeperationTab                matlab.ui.container.Tab
        FileNameEditField              matlab.ui.control.EditField
        FileNameEditFieldLabel         matlab.ui.control.Label
        SaveDataButton                 matlab.ui.control.Button
        SaveGraphButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        USeperationTab                 matlab.ui.container.Tab
        FileNameEditField_2            matlab.ui.control.EditField
        FileNameEditField_2Label       matlab.ui.control.Label
        SaveDataButton_4               matlab.ui.control.Button
        SaveGraphButton_4              matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
        WIATab                         matlab.ui.container.Tab
        FileNameEditField_3            matlab.ui.control.EditField
        FileNameEditField_3Label       matlab.ui.control.Label
        SaveDataButton_5               matlab.ui.control.Button
        SaveGraphButton_5              matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        WIASeperationTab               matlab.ui.container.Tab
        FileNameEditField_4            matlab.ui.control.EditField
        FileNameEditField_4Label       matlab.ui.control.Label
        SaveDataButton_6               matlab.ui.control.Button
        SaveGraphButton_6              matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        RestartButton                  matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        nextpage        % allows the next page to be selected
        Caller     % Stores the name of the previous app screen
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)

        % Stores the variable arrays
        P       % Pressure waveform
        U       % Velocity waveform
        D       % Diameter waveform
        Q       % Volumetric flow rate
        A       % Area waveform
        ECG     % ECG trace
        time    % time waveform
        lnD     % natural log of the diameter waveform
        C       % Wave speed
        dP      % Pressure waveform differential
        dU      % Velocity waveform differential
        dI      % Intensity
        dD      % Diameter waveform differential
        dlnD    % Natural log D waveform differential
        dPfd    % Forward pressure differential
        dPbd    % Backward pressure differential
        dUfd    % Forward velocity differential
        dUbd    % Backward velocity differential
        dIfd    % Forward wave intensity
        dIbd    % Backward wave intensity
        dDfd    % Forward diameter differential
        dDbd    % Backward diameter differential
        Pfd     % Forward pressure waveform
        Pbd     % Backward pressure waveform
        Ufd     % Forward velocity waveform
        Ubd     % Backward velocity waveform
        Dfd     % Forward diameter waveform
        Dbd     % Vackward diameter waveform

        filenameP       % Stores the filename used for P data
        filenameU       % Stores the filename used for U data
        filenameWIA     % Stores the filename used for Wave Inensity Analysis data
        filenameWIAS    % Stores the filename used for WIA seperation data
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, time, C, Method, lnD)
            movegui(app.UIFigure,"center"); % Centers the GUI

            % saves the input arguments as variables
            app.Caller = Caller;
            app.Output = Output;
            app.Method = Method;
            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A = A;
            app.lnD = lnD;
            app.ECG = ECG;
            app.time = time;
            app.C = C;

            
            if app.Method == 1
                % Invasive method

                % Calculating gradients (derivatives)
                app.dP = gradient(app.P, app.time);
                app.dU = gradient(app.U, app.time);

                app.dI = app.dP.*app.dU;                                    % Calculates wave intensity
    
                dIlength = length(app.dI);                                  % Used in plotting the data
                
                % Plots the wave intensity analysis results
                plot(app.UIAxes_2,app.time,app.dI, 'b')                     % Plots the data line
                hold(app.UIAxes_2, 'on')
                plot(app.UIAxes_2,app.time,app.dI, '.g', 'MarkerSize', 10)  % Plots the data points
                hold(app.UIAxes_2, 'off')
                ylabel(app.UIAxes_2,'dI (W/m^2)')                           
                xlabel(app.UIAxes_2, 'time (s)')
                grid(app.UIAxes_2, 'on')
    
                % Calculating wave seperation derivatives
                app.dPfd=(app.dP+(1040*app.C*app.dU))/2;                    % forward P differences
                app.dUfd=(app.dU+(app.dP/(1040*app.C)))/2;                  % forward U differences
                app.dIfd=app.dPfd.*app.dUfd;                                % forward wave intensity            
                app.dPbd=(app.dP-(1040*app.C*app.dU))/2;                    % backward P differences
                app.dUbd=(app.dU-(app.dP/(1040*app.C)))/2;                  % backward U differences
                app.dIbd=app.dPbd.*app.dUbd;                                % backward wave intensity
                
                % Calculating wave seperations
                app.Pfd = min(app.P) + cumtrapz(app.time, app.dPfd);        % forward Pressure wave
                app.Pbd = cumtrapz(app.time, app.dPbd);                     % backward Pressure wave
                app.Ufd = cumtrapz(app.time, app.dUfd);                     % forward velocity wave
                app.Ubd = cumtrapz(app.time, app.dUbd);                     % backward velocity wave
                
                % Plotting pressure wave seperations
                plot(app.UIAxes,app.time,app.P(1:dIlength), 'b', 'LineWidth', 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.time,app.P(1:dIlength), '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes,app.time,app.Pfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Pfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);
                plot(app.UIAxes,app.time,app.Pbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Pbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);               
                hold(app.UIAxes, 'off')
                ylabel(app.UIAxes, 'P (Pa)')
                xlabel(app.UIAxes, 'time (s)')
                legend(app.UIAxes, 'P', '', 'P+', '', 'P-', '')
                grid(app.UIAxes, 'on')
               
                % Plotting velocity wave seperations
                plot(app.UIAxes_5,app.time,app.U, 'b', 'LineWidth', 1)
                hold(app.UIAxes_5, 'on')
                plot(app.UIAxes_5,app.time,app.U, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes_5,app.time, app.Ufd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ufd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);                
                plot(app.UIAxes_5,app.time, app.Ubd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ubd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes_5, 'off')
                ylabel(app.UIAxes_5, 'U (m/s)')
                xlabel(app.UIAxes_5, 'time (s)')
                legend(app.UIAxes_5, 'U', '', 'U+', '', 'U-', '')
                grid(app.UIAxes_5, 'on')
    
                % Plotting wave intensity analysis seperations
                plot(app.UIAxes_3,app.time, app.dI, 'b', 'LineWidth', 1)
                hold(app.UIAxes_3, 'on')
                plot(app.UIAxes_3,app.time, app.dI, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',6)
                plot(app.UIAxes_3,app.time,app.dIfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',6);
                plot(app.UIAxes_3,app.time,app.dIbd, 'color', [0.4660 0.6740 0.1880], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',6);
                hold(app.UIAxes_3, 'off')
                ylabel(app.UIAxes_3, 'dI (W/m^2)')            
                xlabel(app.UIAxes_3, 'time (s)')
                legend(app.UIAxes_3, 'dI', '', 'dI+', '', 'dI-', '')
                grid(app.UIAxes_3, 'on')
    
                % Displaying the wave speed
                app.CTextArea.Value = sprintf('%0.4f m/s',app.C);

            elseif app.Method == 2
                % non-invasive method
                app.dD = gradient(app.D, app.time);                         % derivative of D
                app.dlnD = gradient(app.lnD, app.time);                     % derivative of lnD
                app.dU = gradient(app.U, app.time);                         % derivative of U
                app.dI = app.dD.*app.dU;                                    % wave intensity
                dIlength = length(app.dI); 

                % Plotting dI
                plot(app.UIAxes_2,app.time,app.dI, 'b')
                hold(app.UIAxes_2, 'on')
                plot(app.UIAxes_2,app.time,app.dI, '.g', 'MarkerSize', 10)
                hold(app.UIAxes_2, 'off')
                ylabel(app.UIAxes_2,'dI (W/m^2)')
                xlabel(app.UIAxes_2, 'time (s)')
                grid(app.UIAxes_2, 'on')

                % Calculating wave seperation differences
                app.dDfd = (app.D/2).*(app.dlnD+(app.dU/(2*app.C)));        % forward D differences
                app.dDbd = (-app.D/2).*(app.dlnD-(app.dU/(2*app.C)));       % backward D difference
                app.dUfd = 0.5*(app.dU + 2*app.C*app.dlnD);                 % forward U differencs
                app.dUbd = -0.5*(app.dU - 2*app.C*app.dlnD);                % backward U difference
                app.dIfd=app.dDfd.*app.dUfd;                                % forward wave intensity
                app.dIbd=app.dDbd.*app.dUbd;                                % backward wave intensity
                
                % Calculating wave seperations
                app.Dfd = cumtrapz(app.time, app.dDfd);% forward D
                app.Dbd = cumtrapz(app.time, app.dDbd);                     % backward D
                app.Ufd = cumtrapz(app.time, app.dUfd);                     % forward U
                app.Ubd = cumtrapz(app.time, app.dUbd);                     % backward U
                 
                % Plotting diameter seperations
                plot(app.UIAxes,app.time,app.D(1:dIlength), 'b', 'LineWidth', 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.time,app.D(1:dIlength), '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes,app.time,app.Dfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Dfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);
                plot(app.UIAxes,app.time,app.Dbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Dbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes, 'off')
                ylabel(app.UIAxes, 'D (m)')
                xlabel(app.UIAxes, 'time (s)')
                legend(app.UIAxes, 'D', '', 'D+', '','D-', '')
                grid(app.UIAxes, 'on')
               
                % Plotting velocity seperations
                plot(app.UIAxes_5,app.time,app.U, 'b', 'LineWidth', 1)
                hold(app.UIAxes_5, 'on')
                plot(app.UIAxes_5,app.time,app.U, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes_5,app.time, app.Ufd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ufd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);                
                plot(app.UIAxes_5,app.time, app.Ubd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ubd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes_5, 'off')
                ylabel(app.UIAxes_5, 'U (m/s)')
                xlabel(app.UIAxes_5, 'time (s)')
                legend(app.UIAxes_5, 'U', '', 'U+', '', 'U-', '')
                grid(app.UIAxes_5, 'on')
    
                % Plotting wave intensity seperations
                plot(app.UIAxes_3,app.time, app.dI, 'b', 'LineWidth', 1)
                hold(app.UIAxes_3, 'on')
                plot(app.UIAxes_3,app.time, app.dI, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',6)
                plot(app.UIAxes_3,app.time,app.dIfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',6);
                plot(app.UIAxes_3,app.time,app.dIbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',6);
                hold(app.UIAxes_3, 'off')
                ylabel(app.UIAxes_3, 'dI (W/m^2)')            
                xlabel(app.UIAxes_3, 'time (s)')
                legend(app.UIAxes_3, 'dI', '', 'dI+', '', 'dI-', '')
                grid(app.UIAxes_3, 'on')
    
                % Displaying wave velocity
                app.CTextArea.Value = sprintf('%0.4f m/s',app.C);
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Passes the user back to the previous PU page
            app.nextpage = PU(app,app.Output,app.P,app.U, ...
            app.D,app.Q,app.A,app.ECG,app.time, app.Method,app.lnD);
            pause(0.5)
            delete(app)   
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            % Sets the filename for saving the P data
            app.filenameP = app.FileNameEditField.Value;
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Saves data for P seperaion
            path = uigetdir();                                              % Allows the user to interactively select the directory to save the data
            if isempty(path) == 0                                           % Checks to ensure a path has been selected
            name = fullfile(path,[app.filenameP '.txt']);                   % Collates the filename and path
            fid = fopen(name, 'wt' );                                       % Opens\creates the file in a write format
            head = {'T(s)', 'P(Pa)','Pfd(Pa)','Pbd(Pa)'};                   % Creates a header for the data
            yourCell = [app.time(:), app.P(:), app.Pfd(:), app.Pbd(:)];     % Creates a matrix stroing the data to be saved  
            fprintf(fid,'%s %s %s %s\n',head{:});                           % Writes the header to the file
            fprintf(fid,'%f %f %f %f\n', yourCell');                        % Writes the data to the file
            end
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            % Saves graphs for P seperaion
            path = uigetdir();                                              % Allows the user to interactively select the directory to save the data
            if isempty(path) == 0                                           % Checks to ensure a path has been selected
            name = fullfile(path,[app.filenameP '.png']);                   % Collates the filename and path
            exportgraphics(app.UIAxes,name, 'Resolution', 300)              % Exports the graph to the specified file
            end
           
        end

        % Value changed function: FileNameEditField_2
        function FileNameEditField_2ValueChanged(app, event)
            % Sets the filename for saving the U data
            app.filenameU = app.FileNameEditField_2.Value;
            
        end

        % Button pushed function: SaveDataButton_4
        function SaveDataButton_4Pushed(app, event)
            % Same as for P but for U seperation
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameU '.txt']);
            fid = fopen(name, 'wt' );
            head = {'T(s)', 'U(m/s)','Ufd(m/s)','Ubd(m/s)'};
            yourCell = [app.time(:), app.U(:), app.Ufd(:), app.Ubd(:)];
            fprintf(fid,'%s %s %s %s\n',head{:});
            fprintf(fid,'%f %f %f %f\n', yourCell'); 
            end
        end

        % Button pushed function: SaveGraphButton_4
        function SaveGraphButton_4Pushed(app, event)
            % Same as for P but for U seperation
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameU '.png']);
            exportgraphics(app.UIAxes_5,name, 'Resolution', 300)
            end
        end

        % Value changed function: FileNameEditField_3
        function FileNameEditField_3ValueChanged(app, event)
            % sets the file name for saving the WIA
            app.filenameWIA = app.FileNameEditField_3.Value;
            
        end

        % Button pushed function: SaveDataButton_5
        function SaveDataButton_5Pushed(app, event)
            % Same as for P but for WIA
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameWIA '.txt']);
            fid = fopen(name, 'wt' );
            head = {'T(s)', 'dI'};
            yourCell = [app.time(:), app.dI(:)];
            fprintf(fid,'%s %s\n',head{:});
            fprintf(fid,'%f %f\n', yourCell'); 
            end
        end

        % Button pushed function: SaveGraphButton_5
        function SaveGraphButton_5Pushed(app, event)
            % Same as for P but for WIA
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameWIA '.png']);
            exportgraphics(app.UIAxes_2,name, 'Resolution', 300)
            end
        end

        % Value changed function: FileNameEditField_4
        function FileNameEditField_4ValueChanged(app, event)
            % Sets the filename for saving the WIA seperation
            app.filenameWIAS = app.FileNameEditField_4.Value;
            
        end

        % Button pushed function: SaveDataButton_6
        function SaveDataButton_6Pushed(app, event)
            % Same as P but for WIA seperation
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameWIAS '.txt']);
            fid = fopen(name, 'wt' );
            head = {'T(s)', 'dI','dIfd','dIbd'};
            yourCell = [app.time(:), app.dI(:), app.dIfd(:), app.dIbd(:)];
            fprintf(fid,'%s %s %s %s\n',head{:});
            fprintf(fid,'%f %f %f %f\n', yourCell'); 
            end
        end

        % Button pushed function: SaveGraphButton_6
        function SaveGraphButton_6Pushed(app, event)
            % Same as P but for WIA seperation
            path = uigetdir();
            if isempty(path) == 0
            name = fullfile(path,[app.filenameWIAS '.png']);
            exportgraphics(app.UIAxes_3,name, 'Resolution', 300)
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Confirms the user has saved all the data they need as it will be erased if they progress
            answer = questdlg('Have you saved what you need?', ...
	            'Check','Yes','No', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    check = 1;
                case 'No'                   
                    check = 2;                
            end

            % Closes the app
            if check == 1
                delete(app)
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            % Confirms the user has saved all the data they need as it will be erased if they progress
            answer = questdlg('Have you saved what you need?', ...
	            'Check','Yes','No', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    check = 1;
                case 'No'                   
                    check = 2;                
            end

             % Passes the user back to the homepage
            if check == 1
                app.nextpage = Homepage(app);
                pause(0.5)
                delete(app)
            end
        end

        % Button pushed function: SaveCtoworkspaceButton
        function SaveCtoworkspaceButtonPushed(app, event)
            assignin('base', 'WaveSpeed', app.C) % saves the wave speed to the matlab workspace
        end

        % Value changed function: WanttotryadifferentCEditField
        function WanttotryadifferentCEditFieldValueChanged(app, event)
            app.C = app.WanttotryadifferentCEditField.Value;

            if app.Method == 1
                % Invasive method

                % Calculating gradients (derivatives)
                app.dP = gradient(app.P, app.time);
                app.dU = gradient(app.U, app.time);

                app.dI = app.dP.*app.dU;                                    % Calculates wave intensity
    
                dIlength = length(app.dI);                                  % Used in plotting the data
                
                % Plots the wave intensity analysis results
                plot(app.UIAxes_2,app.time,app.dI, 'b')                     % Plots the data line
                hold(app.UIAxes_2, 'on')
                plot(app.UIAxes_2,app.time,app.dI, '.g', 'MarkerSize', 10)  % Plots the data points
                hold(app.UIAxes_2, 'off')
                ylabel(app.UIAxes_2,'dI (W/m^2)')                           
                xlabel(app.UIAxes_2, 'time (s)')
                grid(app.UIAxes_2, 'on')
    
                % Calculating wave seperation derivatives
                app.dPfd=(app.dP+(1040*app.C*app.dU))/2;                    % forward P differences
                app.dUfd=(app.dU+(app.dP/(1040*app.C)))/2;                  % forward U differences
                app.dIfd=app.dPfd.*app.dUfd;                                % forward wave intensity            
                app.dPbd=(app.dP-(1040*app.C*app.dU))/2;                    % backward P differences
                app.dUbd=(app.dU-(app.dP/(1040*app.C)))/2;                  % backward U differences
                app.dIbd=app.dPbd.*app.dUbd;                                % backward wave intensity
                
                % Calculating wave seperations
                app.Pfd = min(app.P) + cumtrapz(app.time, app.dPfd);        % forward Pressure wave
                app.Pbd = cumtrapz(app.time, app.dPbd);                     % backward Pressure wave
                app.Ufd = cumtrapz(app.time, app.dUfd);                     % forward velocity wave
                app.Ubd = cumtrapz(app.time, app.dUbd);                     % backward velocity wave
                
                % Plotting pressure wave seperations
                plot(app.UIAxes,app.time,app.P(1:dIlength), 'b', 'LineWidth', 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.time,app.P(1:dIlength), '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes,app.time,app.Pfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Pfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);
                plot(app.UIAxes,app.time,app.Pbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Pbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);               
                hold(app.UIAxes, 'off')
                ylabel(app.UIAxes, 'P (Pa)')
                xlabel(app.UIAxes, 'time (s)')
                legend(app.UIAxes, 'P', '', 'P+', '', 'P-', '')
                grid(app.UIAxes, 'on')
               
                % Plotting velocity wave seperations
                plot(app.UIAxes_5,app.time,app.U, 'b', 'LineWidth', 1)
                hold(app.UIAxes_5, 'on')
                plot(app.UIAxes_5,app.time,app.U, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes_5,app.time, app.Ufd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ufd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);                
                plot(app.UIAxes_5,app.time, app.Ubd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ubd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes_5, 'off')
                ylabel(app.UIAxes_5, 'U (m/s)')
                xlabel(app.UIAxes_5, 'time (s)')
                legend(app.UIAxes_5, 'U', '', 'U+', '', 'U-', '')
                grid(app.UIAxes_5, 'on')
    
                % Plotting wave intensity analysis seperations
                plot(app.UIAxes_3,app.time, app.dI, 'b', 'LineWidth', 1)
                hold(app.UIAxes_3, 'on')
                plot(app.UIAxes_3,app.time, app.dI, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',6)
                plot(app.UIAxes_3,app.time,app.dIfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',6);
                plot(app.UIAxes_3,app.time,app.dIbd, 'color', [0.4660 0.6740 0.1880], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',6);
                hold(app.UIAxes_3, 'off')
                ylabel(app.UIAxes_3, 'dI (W/m^2)')            
                xlabel(app.UIAxes_3, 'time (s)')
                legend(app.UIAxes_3, 'dI', '', 'dI+', '', 'dI-', '')
                grid(app.UIAxes_3, 'on')
    
                % Displaying the wave speed
                app.CTextArea.Value = sprintf('%0.4f m/s',app.C);

            elseif app.Method == 2
                % non-invasive method
                app.dD = gradient(app.D, app.time);                         % derivative of D
                app.dlnD = gradient(app.lnD, app.time);                     % derivative of lnD
                app.dU = gradient(app.U, app.time);                         % derivative of U
                app.dI = app.dD.*app.dU;                                    % wave intensity
                dIlength = length(app.dI); 

                % Plotting dI
                plot(app.UIAxes_2,app.time,app.dI, 'b')
                hold(app.UIAxes_2, 'on')
                plot(app.UIAxes_2,app.time,app.dI, '.g', 'MarkerSize', 10)
                hold(app.UIAxes_2, 'off')
                ylabel(app.UIAxes_2,'dI (W/m^2)')
                xlabel(app.UIAxes_2, 'time (s)')
                grid(app.UIAxes_2, 'on')

                % Calculating wave seperation differences
                app.dDfd = (app.D/2).*(app.dlnD+(app.dU/(2*app.C)));        % forward D differences
                app.dDbd = (-app.D/2).*(app.dlnD-(app.dU/(2*app.C)));       % backward D difference
                app.dUfd = 0.5*(app.dU + 2*app.C*app.dlnD);                 % forward U differencs
                app.dUbd = -0.5*(app.dU - 2*app.C*app.dlnD);                % backward U difference
                app.dIfd=app.dDfd.*app.dUfd;                                % forward wave intensity
                app.dIbd=app.dDbd.*app.dUbd;                                % backward wave intensity
                
                % Calculating wave seperations
                app.Dfd = cumtrapz(app.time, app.dDfd);% forward D
                app.Dbd = cumtrapz(app.time, app.dDbd);                     % backward D
                app.Ufd = cumtrapz(app.time, app.dUfd);                     % forward U
                app.Ubd = cumtrapz(app.time, app.dUbd);                     % backward U
                 
                % Plotting diameter seperations
                plot(app.UIAxes,app.time,app.D(1:dIlength), 'b', 'LineWidth', 1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes,app.time,app.D(1:dIlength), '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes,app.time,app.Dfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Dfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);
                plot(app.UIAxes,app.time,app.Dbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes,app.time,app.Dbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes, 'off')
                ylabel(app.UIAxes, 'D (m)')
                xlabel(app.UIAxes, 'time (s)')
                legend(app.UIAxes, 'D', '', 'D+', '','D-', '')
                grid(app.UIAxes, 'on')
               
                % Plotting velocity seperations
                plot(app.UIAxes_5,app.time,app.U, 'b', 'LineWidth', 1)
                hold(app.UIAxes_5, 'on')
                plot(app.UIAxes_5,app.time,app.U, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',4)
                plot(app.UIAxes_5,app.time, app.Ufd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ufd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',4);                
                plot(app.UIAxes_5,app.time, app.Ubd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_5,app.time, app.Ubd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',4);
                hold(app.UIAxes_5, 'off')
                ylabel(app.UIAxes_5, 'U (m/s)')
                xlabel(app.UIAxes_5, 'time (s)')
                legend(app.UIAxes_5, 'U', '', 'U+', '', 'U-', '')
                grid(app.UIAxes_5, 'on')
    
                % Plotting wave intensity seperations
                plot(app.UIAxes_3,app.time, app.dI, 'b', 'LineWidth', 1)
                hold(app.UIAxes_3, 'on')
                plot(app.UIAxes_3,app.time, app.dI, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',6)
                plot(app.UIAxes_3,app.time,app.dIfd, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIfd, '.', 'color', [0 0.4470 0.7410],'MarkerSize',6);
                plot(app.UIAxes_3,app.time,app.dIbd, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1);
                plot(app.UIAxes_3,app.time,app.dIbd, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',6);
                hold(app.UIAxes_3, 'off')
                ylabel(app.UIAxes_3, 'dI (W/m^2)')            
                xlabel(app.UIAxes_3, 'time (s)')
                legend(app.UIAxes_3, 'dI', '', 'dI+', '', 'dI-', '')
                grid(app.UIAxes_3, 'on')
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PDSeperationTab = uitab(app.TabGroup);
            app.PDSeperationTab.Title = 'P / D Seperation';
            app.UIAxes = uiaxes(app.PDSeperationTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SaveGraphButton = uibutton(app.PDSeperationTab, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.SaveDataButton = uibutton(app.PDSeperationTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FileNameEditFieldLabel = uilabel(app.PDSeperationTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.PDSeperationTab, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.USeperationTab = uitab(app.TabGroup);
            app.USeperationTab.Title = 'U Seperation';
            app.UIAxes_5 = uiaxes(app.USeperationTab);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.SaveGraphButton_4 = uibutton(app.USeperationTab, 'push');
            app.SaveGraphButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButton_4Pushed, true);
            app.SaveGraphButton_4.Text = 'Save Graph';
            app.SaveDataButton_4 = uibutton(app.USeperationTab, 'push');
            app.SaveDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_4Pushed, true);
            app.SaveDataButton_4.Text = 'Save Data';
            app.FileNameEditField_2Label = uilabel(app.USeperationTab);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.FileNameEditField_2 = uieditfield(app.USeperationTab, 'text');
            app.FileNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @FileNameEditField_2ValueChanged, true);
            app.WIATab = uitab(app.TabGroup);
            app.WIATab.Title = 'WIA';
            app.UIAxes_2 = uiaxes(app.WIATab);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SaveGraphButton_5 = uibutton(app.WIATab, 'push');
            app.SaveGraphButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButton_5Pushed, true);
            app.SaveGraphButton_5.Text = 'Save Graph';
            app.SaveDataButton_5 = uibutton(app.WIATab, 'push');
            app.SaveDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_5Pushed, true);
            app.SaveDataButton_5.Text = 'Save Data';
            app.FileNameEditField_3Label = uilabel(app.WIATab);
            app.FileNameEditField_3Label.Text = 'File Name';
            app.FileNameEditField_3 = uieditfield(app.WIATab, 'text');
            app.FileNameEditField_3.ValueChangedFcn = createCallbackFcn(app, @FileNameEditField_3ValueChanged, true);
            app.WIASeperationTab = uitab(app.TabGroup);
            app.WIASeperationTab.Title = 'WIA Seperation';
            app.UIAxes_3 = uiaxes(app.WIASeperationTab);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SaveGraphButton_6 = uibutton(app.WIASeperationTab, 'push');
            app.SaveGraphButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButton_6Pushed, true);
            app.SaveGraphButton_6.Text = 'Save Graph';
            app.SaveDataButton_6 = uibutton(app.WIASeperationTab, 'push');
            app.SaveDataButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_6Pushed, true);
            app.SaveDataButton_6.Text = 'Save Data';
            app.FileNameEditField_4Label = uilabel(app.WIASeperationTab);
            app.FileNameEditField_4Label.Text = 'File Name';
            app.FileNameEditField_4 = uieditfield(app.WIASeperationTab, 'text');
            app.FileNameEditField_4.ValueChangedFcn = createCallbackFcn(app, @FileNameEditField_4ValueChanged, true);
            app.CTextAreaLabel = uilabel(app.UIFigure);
            app.CTextAreaLabel.Text = 'C = ';
            app.CTextArea = uitextarea(app.UIFigure);
            app.SaveCtoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.SaveCtoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCtoworkspaceButtonPushed, true);
            app.SaveCtoworkspaceButton.Text = 'Save C to workspace';
            app.WanttotryadifferentCEditFieldLabel = uilabel(app.UIFigure);
            app.WanttotryadifferentCEditFieldLabel.Text = 'Want to try a different C?';
            app.WanttotryadifferentCEditField = uieditfield(app.UIFigure, 'numeric');
            app.WanttotryadifferentCEditField.ValueChangedFcn = createCallbackFcn(app, @WanttotryadifferentCEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Outputpage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end