classdef PUadjust < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Button_4    matlab.ui.control.Button
        TextArea_5  matlab.ui.control.TextArea
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        TextArea_4  matlab.ui.control.TextArea
        Button      matlab.ui.control.Button
        TextArea_3  matlab.ui.control.TextArea
        NextButton  matlab.ui.control.Button
        UIAxes2     matlab.ui.control.UIAxes
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage        % allows the next page to be selected
        CallingApp      % Stores the name of the previous app screen
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)

        % Stores the variable arrays
        P
        U
        D
        lnD
        Q
        A
        ECG
        time

        % stores the adjusted signals without overwriting the data
        Ptemp
        Utemp
        lnDtemp

        % tracks how the signals have been shifted
        countP = 0
        countU = 0
        countD = 0

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, time, Method)
            movegui(app.UIFigure,"center"); % center the GUI        
            % saves the input arguments as variables
            app.CallingApp = Caller;
            app.Output = Output;
            app.Method = Method;
            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A = A;
            app.ECG = ECG;
            app.time = time;
            % sets the temporary variables and calculates lnD
            app.Ptemp = P;
            app.Utemp = U;
            app.lnD = log(D);
            app.lnDtemp = app.lnD;               
            if app.Method == 1 
                % If using invasive method plots a P and U on the axis
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.P, 'b','LineWidth',1.5);
                hold(app.UIAxes2, 'on')
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.P, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'P (Pa)')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.U, 'k', 'LineWidth',1.5);              
                app.UIAxes2.YAxis(2).Color = 'k';
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.U, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'P', '', 'U', '')
                
                % alignment lines to help the user
                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                grid(app.UIAxes2, 'on')
                hold(app.UIAxes2, "off")
                

            elseif app.Method == 3
                % If using windkessel method plots a P and U on the axis
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.P, 'b','LineWidth',1.5);
                hold(app.UIAxes2, 'on')
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.P, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'P (Pa)')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.U, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.U, 'og', 'MarkerSize', 4)
                app.UIAxes2.YAxis(2).Color = 'k';
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'P', '', 'U', '')
                grid(app.UIAxes2, 'on')

                % alignment lines to help the user
                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--') 
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                hold(app.UIAxes2, "off")
                
            
            elseif app.Method == 2
                % If using non-invasive method plots a lnD and U on the axis
                app.TextArea_5.Value = 'lnD';
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnD, 'b','LineWidth',1.5);
                hold(app.UIAxes2, "on")
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnD, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'lnD')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.U, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.U, 'og', 'MarkerSize', 4)
                app.UIAxes2.YAxis(2).Color = 'k';
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')  
                %legend(app.UIAxes2, 'lnD', '', 'U', '')

                % alignment lines to help the user
                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.lnDtemp);
                xline(app.UIAxes2,app.time(ind), '--')
                grid(app.UIAxes2, 'on')
                hold(app.UIAxes2, "off")
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % code to shift the U data left for Invasive
            if app.Method == 1
                % if Invasive Method
                app.countU = app.countU +1; % keeps track of how the signal has been shifted
                zero = zeros(app.countU,1); % makes a zero array of length the number of values removed from the waveform
                app.Utemp = [app.U((app.countU+1):end);zero]; % creates a temporry array the same as before but some initial values removed and zeros added at the end
                cla(app.UIAxes2)            % Clears the axis
                yyaxis(app.UIAxes2, 'left') % selects the left hand vertical axis
                plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5); % plots the data on the left y axis
                hold(app.UIAxes2, 'on')     % holds the axis so multiple graphs can be plotted
                yyaxis(app.UIAxes2, 'left') 
                plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4) % plots the data points on the left axis
                ylabel(app.UIAxes2, 'P (Pa)') % adds a label to the left y axis
                yyaxis(app.UIAxes2, 'right') % selects the right hand y axis    
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5); % plots on the right hand y axis
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4) % plots the data points on the right hand axis
                ylabel(app.UIAxes2, 'U (m/s)')  % adds right hand axis label
                xlabel(app.UIAxes2, 'time (s)') % adds x-axis label
                %legend(app.UIAxes2, 'P', '', 'U', '')

                % alignment lines to help the user
                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                grid(app.UIAxes2, 'on')

            elseif app.Method == 3
                % same as for the invasive method but for the Windkessel
                % technique
                app.countU = app.countU +1;
                zero = zeros(app.countU,1);
                app.Utemp = [app.U((app.countU+1):end);zero];
                cla(app.UIAxes2)
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                hold(app.UIAxes2, 'on')
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'P (Pa)')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'P', '', 'U', '')

                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                grid(app.UIAxes2, 'on')
                            
            elseif app.Method == 2
                % same as for the other techniques but for the non-invasive
                % method
                app.countU = app.countU +1;
                zero = zeros(app.countU,1);
                app.Utemp = [app.U((app.countU+1):end);zero];
                cla(app.UIAxes2)
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnDtemp, 'b','LineWidth',1.5);
                hold(app.UIAxes2, "on")
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnDtemp, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'lnD')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'lnD', '', 'U', '')

                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.lnDtemp);
                xline(app.UIAxes2,app.time(ind), '--')
                grid(app.UIAxes2, 'on')
                
            end
           hold(app.UIAxes2, "off") % stops holding the axis
        end

        % Button pushed function: Button_2
        function Button_3Pushed(app, event)
            % code to shift the U data right for Invasive
            % same as for the left but adds to the start of the signal and
            % removes from the end
            if app.Method == 1
                if app.countU > 0           % as long as the signal has been shifted left previously
                    app.countU = app.countU -1;
                    zero = zeros(app.countU,1);
                    app.Utemp = [app.U((app.countU+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, "on")
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'P (Pa)')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')
                    %legend(app.UIAxes2, 'P', '', 'U', '')

                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.Ptemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    grid(app.UIAxes2, 'on')
                    
                else
                end
            elseif app.Method == 3
                if app.countU > 0           % as long as the signal has been shifted left previously
                    app.countU = app.countU -1;
                    zero = zeros(app.countU,1);
                    app.Utemp = [app.U((app.countU+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, "on")
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'P (Pa)')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')
                    %legend(app.UIAxes2, 'P', '', 'U', '')

                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.Ptemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    grid(app.UIAxes2, 'on')
                    
                else
                end
           % code to shift the U data right for Non-Invasive     
           elseif app.Method == 2
                if app.countU > 0           % as long as the signal has been shifted left previously
                    app.countU = app.countU -1;
                    zero = zeros(app.countU,1);
                    app.Utemp = [app.U((app.countU+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.lnDtemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, "on")
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.lnDtemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'lnD')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')
                    %legend(app.UIAxes2, 'lnD', '', 'U', '')

                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.lnDtemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    grid(app.UIAxes2, 'on')
                    
                else
                end
            end
            hold(app.UIAxes2, "off")
        end

        % Button pushed function: Button_3
        function Button_4Pushed(app, event)
            % code to shift the P data left for Invasive
            % same as for U (see above for comments)
            if app.Method == 1
                app.countP = app.countP +1;
                zero = app.P(end)*ones(app.countP,1);
                app.Ptemp = [app.P((app.countP+1):end);zero];
                cla(app.UIAxes2)
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                hold(app.UIAxes2, "on")
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'P (Pa)')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'P', '', 'U', '')

                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                hold(app.UIAxes2, "off")
                grid(app.UIAxes2, 'on')

            elseif app.Method == 3
                app.countP = app.countP +1;
                zero = app.P(end)*ones(app.countP,1);
                app.Ptemp = [app.P((app.countP+1):end);zero];
                cla(app.UIAxes2)
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                hold(app.UIAxes2, "on")
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'P (Pa)')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'P', '', 'U', '')
                
                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.Ptemp);
                xline(app.UIAxes2,app.time(ind), '--')
                hold(app.UIAxes2, "off")
                grid(app.UIAxes2, 'on')

            % code to shift the P data left for Non-Invasive
            elseif app.Method == 2
                app.countD = app.countD +1;
                zero = app.lnD(end)*ones(app.countD,1);
                app.lnDtemp = [app.lnD((app.countD+1):end);zero];
                cla(app.UIAxes2)
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnDtemp, 'b','LineWidth',1.5);
                hold(app.UIAxes2, "on")
                yyaxis(app.UIAxes2, 'left')
                plot(app.UIAxes2,app.time,app.lnDtemp, 'or', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'lnD')
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                yyaxis(app.UIAxes2, 'right')
                plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                ylabel(app.UIAxes2, 'U (m/s)')
                xlabel(app.UIAxes2, 'time (s)')
                %legend(app.UIAxes2, 'lnD', '', 'U', '')

                yline(app.UIAxes2,0,'--')
                xline(app.UIAxes2,0, '--')
                [~,ind] = min(app.lnDtemp);
                xline(app.UIAxes2,app.time(ind), '--')
                hold(app.UIAxes2, "off")
                grid(app.UIAxes2, 'on')
            end
            
        end

        % Button pushed function: Button_4
        function Button_5Pushed(app, event)
            % code to shift the P data right for Invasive
            % same as for U (see above for comments)
            if app.Method == 1
                if app.countP > 0           % as long as the signal has been shifted left previously
                    app.countP = app.countP -1;
                    zero = app.P(end)*ones(app.countP,1);
                    app.Ptemp = [app.P((app.countP+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, 'on')
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'P (Pa)')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')
                    %legend(app.UIAxes2,'P','','U','')

                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.Ptemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    hold(app.UIAxes2, "off")
                    grid(app.UIAxes2, 'on')
                end

            elseif app.Method == 3
                if app.countP > 0           % as long as the signal has been shifted left previously
                    app.countP = app.countP -1;
                    zero = app.P(end)*ones(app.countP,1);
                    app.Ptemp = [app.P((app.countP+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, 'on')
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.Ptemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'P (Pa)')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')
                    %legend(app.UIAxes2,'P','','U','')

                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.Ptemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    hold(app.UIAxes2, "off")
                    grid(app.UIAxes2, 'on')
                else
                end

            % code to shift the P data right for Non-Invasive    
            elseif app.Method == 2
                    if app.countD > 0       % as long as the signal has been shifted left previously
                    app.countD = app.countD -1;
                    zero = app.lnD(end)*ones(app.countD,1);
                    app.lnDtemp = [app.lnD((app.countD+1):end);zero];
                    cla(app.UIAxes2)
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.lnDtemp, 'b','LineWidth',1.5);
                    hold(app.UIAxes2, "on")
                    yyaxis(app.UIAxes2, 'left')
                    plot(app.UIAxes2,app.time,app.lnDtemp, 'or', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'lnD')
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time, app.Utemp, 'k', 'LineWidth',1.5);
                    yyaxis(app.UIAxes2, 'right')
                    plot(app.UIAxes2,app.time,app.Utemp, 'og', 'MarkerSize', 4)
                    ylabel(app.UIAxes2, 'U (m/s)')
                    xlabel(app.UIAxes2, 'time (s)')

                    %legend(app.UIAxes2, 'lnD','', 'U','')
                    yline(app.UIAxes2,0,'--')
                    xline(app.UIAxes2,0, '--')
                    [~,ind] = min(app.lnDtemp);
                    xline(app.UIAxes2,app.time(ind), '--')
                    hold(app.UIAxes2, "off")
                    grid(app.UIAxes2, 'on')
                else
                    end
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.Method == 3      % takes users to the windkessel analysis
                % updates the arrays with the new ones
                app.P = app.Ptemp;
                app.U = app.Utemp;
                app.lnD = app.lnDtemp;
                
                app.nextpage = Windkessel(app,app.Output,app.P,app.U,app.D,app.Q,app.A,app.ECG, app.time,app.Method); 
                pause(0.5)
                delete(app)  
            else                    % takes users to the output page
                % updates the arrays with the new ones
                app.P = app.Ptemp;
                app.U = app.Utemp;
                app.lnD = app.lnDtemp;
                
                app.nextpage = PU(app,app.Output,app.P,app.U, ...
                app.D,app.Q,app.A,app.ECG,app.time, app.Method,app.lnD);
                pause(0.5)
                delete(app) 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'P')
            zlabel(app.UIAxes2, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'U'};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_2.Text = '>>';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_3.Text = '<<';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_5.Value = {'P'};
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_4.Text = '>>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PUadjust(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end