classdef Pt_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Panel             matlab.ui.container.Panel
        SmoothDataButton  matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        BackButton        matlab.ui.control.Button
        NextButton        matlab.ui.control.Button
        UIAxes_4          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage        % allows the next page to be selected
        Callingapp      % Stores the name of the previous app screen
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)

        % Stores the variable arrays
        P
        U
        D
        Q
        A
        ECG

        % Stores the input file types of the relevant variables
        Pvalue
        Uvalue
        Dvalue
        Qvalue
        Avalue
        ECGvalue

        % Stores the units of the inputs
        P_unit
        U_unit
        D_unit
        Q_unit
        A_unit
        Onefile

        Othersignal % No longer needed
        time        % stores the time vector
        timevalue   % stores the input file type of the time vector
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, Pvalue, Uvalue, Dvalue, Qvalue, Avalue, ECGvalue, P_unit, U_unit, D_unit, Q_unit, A_unit, Onefile, Othersignal, time_type, time, Method)
            movegui(app.UIFigure,"center");         % Centers the GUI
            app.UIFigure.WindowState = 'maximized'; % Maximises the GUI to fill the screen (makes graphs easier to read)
            
            % Stores the input arguments as app variables
            app.Callingapp = Caller;                
            app.Output = Output;
            app.Method = Method;

            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A = A;
            app.ECG = ECG;
            app.time = time;

            app.Pvalue = Pvalue;
            app.Uvalue = Uvalue;
            app.Dvalue = Dvalue;
            app.Qvalue = Qvalue;
            app.Avalue = Avalue;
            app.timevalue = time_type;
            app.ECGvalue = ECGvalue;

            app.P_unit = P_unit;
            app.U_unit = U_unit;
            app.D_unit = D_unit;
            app.Q_unit = Q_unit;
            app.A_unit = A_unit;

            app.Onefile = Onefile;
            app.Othersignal = Othersignal;
            
            
            % Plots all of the non-zero variables on each axis
            if isempty(app.P)
            else
                % If P vector exists
                plot(app.UIAxes, app.time, app.P, 'b');                 % Plots the P vector line
                hold(app.UIAxes, 'on')                                  % Holds the axis so multiple data lines can be plotted on one axis
                plot(app.UIAxes,app.time,app.P, '.r', 'MarkerSize', 8)  % Plots the P vector data points
                hold(app.UIAxes, 'off')                                 % Stops holding the axis                                     
                grid(app.UIAxes, 'on')                                  % Adds the grid to the axis
            end

            % Rest are the same as P but for each variable
            if isempty(app.U)
            else            
            plot(app.UIAxes_2, app.time,app.U, 'b');
            hold(app.UIAxes_2, 'on')
            plot(app.UIAxes_2,app.time,app.U, '.r', 'MarkerSize', 8)
            hold(app.UIAxes_2, 'off')
            grid(app.UIAxes_2, 'on')
            end
            if isempty(app.D)
            else            
            plot(app.UIAxes_3, app.time, app.D, 'b')
            hold(app.UIAxes_3, 'on')
            plot(app.UIAxes_3,app.time,app.D, '.r', 'MarkerSize', 8)
            hold(app.UIAxes_3, 'off')
            grid(app.UIAxes_3, 'on')
            end
            if isempty(app.ECG)
            else            
            plot(app.UIAxes_4, app.time, app.ECG, 'b');
            hold(app.UIAxes_4, 'on')
            plot(app.UIAxes_4,app.time,app.ECG, '.r', 'MarkerSize', 8)
            hold(app.UIAxes_4, 'off')
            grid(app.UIAxes_4, 'on')
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Confirms the user is happy the correct data has been selected
            answer = questdlg('Are you happy with these graphs?', ...
	            'Check','Yes','No', 'No');
            % Handle response and define a variable based off the response
            switch answer
                case 'Yes'
                    check = 1;
                case 'No'                   
                    check = 2;                
            end            
            % Creates a question for the case an ECG with multiple QRS
            % peaks has been uploaded
            prompt = {'Input QRS peak to analyse from'};
            dlgtitle = 'QRS peak';
            dims = [1 35];
            definput = {'1'};                     
            % If there is an ECG this detects R peaks
            if app.Method == 1 || 3
                % if either Invasive / Windkessel technique was selected
                if isempty(app.ECG) == 0                            % Checks if an ECG exists
                    % if yes
                    peak = inputdlg(prompt,dlgtitle,dims,definput); % Displays the message created above
                    index=detectR3(app.time,app.ECG,app.P,200);     % Calls a function to detect the the QRS peaks and stores them in an array
                    x= str2double(peak);                            % converts the user input from string to double
                    y=x+1;                                          % sets the seconf ECG point as the one after the user input
                    P_temp=app.P(index(x):index(y));                % Selects the range of P values based on the ECG peaks selected by the user
                    U_temp=app.U(index(x):index(y));                % Selects the range of U values based on the ECG peaks selected by the user
                    t_temp=app.time(index(x):index(y));             % Selects the range of t values based on the ECG peaks selected by the user
                else
                    % if no, then vartiables don't change but stores in a
                    % temprary variable
                    P_temp = app.P;
                    U_temp = app.U;
                    t_temp = app.time;
                end                   
                    % Overwrites the exsisting variables with the new
                    % arrays 
                    app.P = P_temp;
                    app.U = U_temp;
                    t1 = t_temp - t_temp(1);    % normalises the time vector so it sstarts at zero
                    app.time = t1;              % Overwrites with the normalised time array
                
                if check == 1 % Checks if the user was happy with the graphs
                    % if yes it moves to the waveform alignment page
                    % (PUadjust)
                    app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG,app.time, app.Method);
                    pause(0.5)                  % Pauses while the next screen initiates
                    delete(app)                 % Closes the current screen 
                end
            elseif app.Method == 2
                % if non-invasive method selected
                if isempty(app.ECG) == 0
                    % same as for the invasive / windkessel branch but for
                    % the non-invasive variables (see  above for comments)
                    peak = inputdlg(prompt,dlgtitle,dims,definput);
                    index=detectR3(app.time,app.ECG,app.lnD,200);
                    x= str2double(peak); %input(' what is the number of the first QRS peaks ?');
                    y=x+1;
                    lnD_temp=app.lnD(index(x):index(y));
                    U_temp=app.U(index(x):index(y));
                    t_temp=app.time(index(x):index(y));
                else
                    lnD_temp = app.lnD;
                    U_temp = app.U;
                    t_temp = app.time;
                end
                    
                    app.lnD = lnD_temp;
                    app.U = U_temp;
                    t1 = t_temp - t_temp(1);
                    app.time = t1;
                
                if check == 1
                    app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG,app.time, app.Method);
                    pause(0.5)
                    delete(app)  
                end
            end
% Function that detect R-wive of input signal
% using differentiation and then a new threshold
function [r_wave]=detectR3(time,signal1,signal2,threshold)
t=length(time);         % stores the length of the time array
s=length(signal1);      % stores the length of the signal array (ECG signal)
grad=diff(signal1);     % calculates the gradient
a1=1;                   % Variable to keep track of position in while loops 
i1=1;                   % Variable to keep track of position in while loops 
while i1<s              % tests whether the gradient of every index point point is smaller than the threshold value 
  if grad(i1)<-threshold
    q1=i1;              % if yes it stores the index
    while q1>0          
      if grad(q1)>0     % tests whether the gradient of the differential is postiive (detecting R peak)
        r_wave(a1)=q1+1; % stores the index value of the R peak
        a1=a1+1;
        q1=0;           % stops the while loop if an R peak detected
      end
      q1=q1-1;          % works backwards to try and find the r peak
    end
    while i1<s && grad(i1)<0 % While no R peak detected ( as gradient is negative) move to the next index point
      i1=i1+1;
    end
  end
  i1=i1+1;
end
in1=find(t==t(1));      % gets the first time value (typically 0)
in2=length(signal2);    % gets the length of the second signal (P or lnD)
[r_wave]=[in1 r_wave in2]; % stores the indexs of all of the R peaks
end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % passes the user back to the previous page
            app.nextpage = Inputpage(app, app.Output,app.Pvalue, app.Uvalue, app.Dvalue, ...
                app.Qvalue, app.Avalue,app.ECGvalue, app.P_unit, app.U_unit, app.D_unit, ...
                app.Q_unit, app.A_unit, app.Onefile, app.Othersignal,app.timevalue, app.Method);
            pause(0.5)
            delete(app)  
        end

        % Button pushed function: SmoothDataButton
        function SmoothDataButtonPushed(app, event)
            % takes the user to a new display that will allow them to
            % smooth their data
            app.nextpage = clean(app,app.Output,app.P,app.U, ...
            app.D,app.Q,app.A,app.ECG,app.time, app.Method);
            pause(0.5)
            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'P/t graph')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'P (Pa)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'D/t graph')
            xlabel(app.UIAxes_3, 't (s)')
            ylabel(app.UIAxes_3, 'D (mm)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'U/t graph')
            xlabel(app.UIAxes_2, 't (s)')
            ylabel(app.UIAxes_2, 'U (m/s)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'ECG/t graph')
            xlabel(app.UIAxes_4, 't (s)')
            ylabel(app.UIAxes_4, 'ECG')
            zlabel(app.UIAxes_4, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.Panel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Visible = 'off';
            app.SmoothDataButton = uibutton(app.Panel, 'push');
            app.SmoothDataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothDataButtonPushed, true);
            app.SmoothDataButton.Text = 'Smooth Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pt_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end