classdef Ultrasound_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SmoothDataButton  matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        BackButton        matlab.ui.control.Button
        NextButton        matlab.ui.control.Button
        TabGroup          matlab.ui.container.TabGroup
        DTab              matlab.ui.container.Tab
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UTab              matlab.ui.container.Tab
        UIAxes_7          matlab.ui.control.UIAxes
        UIAxes_6          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        nextpage
        Callingapp
        Output % Description
        Method

        P
        U
        D
        Q
        A
        ECG

        Pvalue
        Uvalue
        Dvalue
        Qvalue
        Avalue
        ECGvalue

        P_unit
        U_unit
        D_unit
        Q_unit
        A_unit
        Onefile

        Othersignal
        time
        timevalue 
        J
        K
        MinD = 0;
        MaxD = 1;
        MinU = 0;
        MaxU = 1;

        tline
        bline
        count  = 0
        blinetemp

        %Thresholding
        Dthresh = 0.09;
        Dsens = 0.7;
        Dwindow = 20;
        Uthresh = 0.15;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, Pvalue, Uvalue, Dvalue, Qvalue, Avalue, ECGvalue, P_unit, U_unit, D_unit, Q_unit, A_unit, Onefile, Othersignal, time_type, time, Method)
            movegui(app.UIFigure,"center"); 
            app.UIFigure.WindowState = 'maximized';
            % Initialising variables
            app.Callingapp = Caller;
            app.Output = Output;
            app.Method = Method;          
            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A = A;
            app.ECG = ECG;
            app.time = time;            
            app.Pvalue = Pvalue;
            app.Uvalue = Uvalue;
            app.Dvalue = Dvalue;
            app.Qvalue = Qvalue;
            app.Avalue = Avalue;
            app.timevalue = time_type;
            app.ECGvalue = ECGvalue;
            app.P_unit = P_unit;
            app.U_unit = U_unit;
            app.D_unit = D_unit;
            app.Q_unit = Q_unit;
            app.A_unit = A_unit;
            app.Onefile = Onefile;
            app.Othersignal = Othersignal;   
            %reads the dicom files and information
            Im_D = dicomread(app.D); % 
            Information_D = dicominfo(app.D);
            Im_V = dicomread(app.U); % 
            Information_V = dicominfo(app.U);
            
            [Images_D, Frames_D] = concatenate_frames_Mmode(Im_D,Information_D);
            [ECG_signal_D] = extract_ecg_new(Images_D);            
            [Preliminary_points_D, Input_data_D, Analysis_data_D] = extract_diameter_threshold(Images_D, ECG_signal_D, Frames_D);
            save([name, '_diameter'],'Images_D', 'ECG_signal_D', 'Preliminary_points_D', 'Input_data_D', 'Analysis_data_D', 'Information_D')
            save(name, 'Im_D', '-v7.3')
            
            [Images_V, Frames_V] = concatenate_frames_Mmode(Im_V,Information_V);
            [ECG_signal_V] = extract_ecg_new(Images_V);
            [Preliminary_points_V, Input_data_V, Analysis_data_V] = extract_velocity_threshold(Images_V, ECG_signal_V, Frames_V);
            save([name, '_velocity'],'Images_V', 'ECG_signal_V', 'Preliminary_points_V', 'Input_data_V', 'Analysis_data_V', 'Information_V')
            save(name, 'Im_V', '-v7.3')
                    
            app.D = sgolayfilt(Analysis_data_D.diameter(1), 7, 21);
            app.U = sgolayfilt(Analysis_data_V.velocity(1), 7, 21);
            imshow(Images_D.Im_each_color(1).Photo, 'Parent', app.UIAxes_2)
            plot(app.UIAxes_3, app.D)
            imshow(Images_V.Im_each_color(1).Photo, 'Parent', app.UIAxes_6)
            plot(app.UIAxes_7, app.U)
        end

        % Button pushed function: SmoothDataButton
        function SmoothDataButtonPushed(app, event)
            % takes the user to a new display that will allow them to
            % smooth their data
            if length(app.D) >= length(app.U)
                while length(app.D) > length(app.U)
                    app.D(end) = [];
                end
            else
                while length(app.U) > length(app.D)
                    app.U(end) = [];
                end
            end

            if app.U_unit == "mm/s"
                app.U = app.U /1000;
            end
            if app.D_unit == "cm"
                app.D = app.D /10;
            elseif app.D_unit == "mm"
                app.D = app.D / 1000;
            end
            
            app.time = 1:length(app.D);
            app.D = app.D';
            app.U = app.U';

            app.nextpage = clean(app,app.Output,app.P,app.U, ...
            app.D,app.Q,app.A,app.ECG,app.time, app.Method);
            pause(0.5)
            delete(app) 
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.nextpage = Inputpage(app, app.Output,app.Pvalue, app.Uvalue, app.Dvalue, ...
                app.Qvalue, app.Avalue,app.ECGvalue, app.P_unit, app.U_unit, app.D_unit, ...
                app.Q_unit, app.A_unit, app.Onefile, app.Othersignal,app.timevalue, app.Method);
            pause(0.5)
            delete(app) 
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if length(app.D) >= length(app.U)
                while length(app.D) > length(app.U)
                    app.D(end) = [];
                end
            else
                while length(app.U) > length(app.D)
                    app.U(end) = [];
                end
            end
            if app.U_unit == "mm/s"
                app.U = app.U /1000;
            end
            if app.D_unit == "cm"
                app.D = app.D /10;
            elseif app.D_unit == "mm"
                app.D = app.D / 1000;
            end           
            app.time = 1:length(app.D);
            app.D = app.D';
            app.U = app.U';
            app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG,app.time, app.Method);
                    pause(0.5)
                    delete(app) 
        end

        % Callback function
        function MinDEditFieldValueChanged(app, event)
            app.MinD = app.MinDEditField.Value;
            app.D = rescale(app.D, app.MinD, app.MaxD);   
            plot(app.UIAxes_3, app.D)
        end

        % Callback function
        function MaxDEditFieldValueChanged(app, event)
            app.MaxD = app.MaxDEditField.Value;
            app.D = rescale(app.D, app.MinD, app.MaxD);   
            plot(app.UIAxes_3, app.D)
            
        end

        % Callback function
        function MinUEditFieldValueChanged(app, event)
            app.MinU = app.MinUEditField.Value;
            app.U = rescale(linesmooth, app.MinU, app.MaxU);
            plot(app.UIAxes_7, app.U)
            
        end

        % Callback function
        function MaxUEditFieldValueChanged(app, event)
            app.MaxU = app.MaxUEditField.Value;
            app.U = rescale(linesmooth, app.MinU, app.MaxU);
            plot(app.UIAxes_7, app.U)
        end

        % Callback function
        function ButtonPushed(app, event)
  
        end

        % Callback function
        function Button_2Pushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = 'D';
            app.UIAxes_2 = uiaxes(app.DTab);
            title(app.UIAxes_2, 'Diameter Ultrasound')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.DTab);
            title(app.UIAxes_3, 'Extracted Diameter')
            xlabel(app.UIAxes_3, 't')
            ylabel(app.UIAxes_3, 'D')
            zlabel(app.UIAxes_3, 'Z')
            app.UTab = uitab(app.TabGroup);
            app.UTab.Title = 'U';
            app.UIAxes_6 = uiaxes(app.UTab);
            title(app.UIAxes_6, 'Velocity Ultrasound')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UTab);
            title(app.UIAxes_7, 'Extracted Velocity')
            xlabel(app.UIAxes_7, 't')
            ylabel(app.UIAxes_7, 'U')
            zlabel(app.UIAxes_7, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Visible = 'off';
            app.SmoothDataButton = uibutton(app.UIFigure, 'push');
            app.SmoothDataButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothDataButtonPushed, true);
            app.SmoothDataButton.Text = 'Smooth Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ultrasound_new(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end