classdef Windkessel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BackButton                      matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        SaveGraphButton                 matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        TimeofstartofdiastoleEditField  matlab.ui.control.NumericEditField
        TimeofstartofdiastoleEditFieldLabel  matlab.ui.control.Label
        BeatDurationEditField           matlab.ui.control.NumericEditField
        BeatDurationEditFieldLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nextpage        % allows the next page to be selected
        Callingapp      % Stores the name of the previous app screen
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)
        
        % Stores the variable arrays
        P
        U
        D
        Q
        A2
        ECG
        time

        filename        % for saving the data

        Tb              % duration of beat
        Tn              % time of start of diastole
        Pr              % reservoire pressure waveform
        Prd             % exponential fit
        Pdif            % difference between reservoire pressure and pressure

        A
        B
        Pinf
        Pn
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, time, Method)
            movegui(app.UIFigure,"center"); % center the GUI

            % saves the input arguments as variables
            app.Caller = Caller;
            app.Output = Output;
            app.Method = Method;
            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A2 = A;
            app.ECG = ECG;
            app.time = time;

            % plots P vs t on the axis with labels and grid
            plot(app.UIAxes, app.time, app.P)
            ylabel(app.UIAxes,'P (Pa)')
            xlabel(app.UIAxes, 'time (s)')
            grid(app.UIAxes, 'on')
        end

        % Value changed function: BeatDurationEditField
        function BeatDurationEditFieldValueChanged(app, event)
            app.Tb = app.BeatDurationEditField.Value; % sets the time of beat duration
            
        end

        % Value changed function: TimeofstartofdiastoleEditField
        function TimeofstartofdiastoleEditFieldValueChanged(app, event)
            app.Tn = app.TimeofstartofdiastoleEditField.Value; % this is automatically calculated so not needed but left as a backup
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%   inputs  P    - pressure starting at diastolic pressure
%           Tb   - duration of beat
%           Tn   - time of start of diastole (if missing it is calculated
%                   found by the function; if ==0 it is done interactively
%   outputs Pr   - reservoir pressure waveform
%           A    - rate constant relating Pw to U
%           B    - rate constant = 1/tau
%           Pinf - pressure asymptote
%           Tn   - detected time of the dichrotic notch
%           Pn   - pressure at dichrotic notch
            
sP=size(app.P);                 % stores the size of P (mxn)
if (sP(1)>sP(2))                % ensure P is correctly dimentionalised (vector)
    app.P=app.P';
end

p=app.P;                        % creates a temporary p variable
Nb=length(app.P)-1;             % stores the length of P
t = (0:Nb)*app.Tb/Nb;           % creates a time variable of the correct length

% determine Tn automatically
% find the minimum dp
    dp= fsg721(p);              % calls a first derivative savgol filter on P
    [~,nn]=min(dp);             % calculates the minimum of this derivative of P
    tn=t(nn);                   % calculates the time at this mimum


% calculate moments of pressure during diastole using model d=a*exp(-bt)+c
pd=p(nn:end);
td=t(nn:end)-tn;
Td=app.Tb-tn;
E0=mean(pd);
E1=kexpint(pd-E0,Td,1/Td);
E2=kexpint(pd-E0,Td,2/Td);
r=E2(end)/E1(end);

% use polynomial fit to invert R(B)
% coeffs obtained by p=polyfit((R-3),B,4)
polyB=[198.7882 -427.3471  350.9809 -148.1055   28.1913];
BTd=polyval(polyB,r-3);
if (BTd>10)
    str=['BTd = ' num2str(BTd,3) '  diastolic time constant is out of expected bounds'];
    disp(str);
end

% given b calculate a from E1
e1=exp(1);
if (BTd==1)
%    denom= - (e1-1)*(1-exp(-BTd))/BTd;
    denom= (3-e1-1/e1);
else
    denom=(1-e1*exp(-BTd))/(BTd-1) - (e1-1)*(1-exp(-BTd))/BTd;
end
a=E1(end)/(Td*denom);

% Given b & a calculate c from E0
c=E0 - a*(1-exp(-BTd))/BTd;
% calculate b in s
b=BTd/Td;
pinf=c;
prd=a*exp(-b*td)+c;

% new fitting algorithm (31/07/08)
options=optimset('TolX',1e-6);
afind = @ (aa) sum((p(nn:end) - dias_int(p,t,aa,b,pinf,nn)).^2);
aa=fminsearch(afind,0,options);
pr=beat_int(p,t,aa,b,pinf);

% Plotting the output data
plot(app.UIAxes, t,p, 'b', 'LineWidth', 1)                                      % Plots the pressure waveform 
hold(app.UIAxes, 'on')                                                          
plot(app.UIAxes, t,p, '.', 'color', [0.6350 0.0780 0.1840], 'MarkerSize',6)     % Plots the pressure data points
grid(app.UIAxes, 'on')                                                          % Adds the grid
plot(app.UIAxes,t,pr, 'color', [0.8500 0.3250 0.0980], 'LineWidth', 1)          % Plots the reservoir pressure
plot(app.UIAxes,t,pr, '.', 'color', [0 0.4470 0.7410],'MarkerSize',6)           % Plots the resercoir pressure data points
plot(app.UIAxes,t ,p-pr, 'color', [0.9290 0.6940 0.1250], 'LineWidth', 1)       % Plots the excess pressure
plot(app.UIAxes,t ,p-pr, '.', 'color', [0.4940 0.1840 0.5560],'MarkerSize',6)   % Plots the excess pressure data points
plot(app.UIAxes,t(nn:end),prd,'m')                                              % Plots the fitted exponential
xlabel(app.UIAxes,'t (s)')
ylabel(app.UIAxes,'P (Pa)')
legend(app.UIAxes, 'Pressure', '', 'Reservoir pressure waveform', '', 'Wave pressure', '', 'Data fitted by a single exponential')
hold(app.UIAxes, 'off')
grid(app.UIAxes, 'on')


% stores the calculated outputs as app variables
app.Pr=pr;
app.P = p;
app.Pdif = p-pr;
temps = zeros(1,(nn-1));
app.Prd = cat(2,temps,prd);

app.A=aa;
app.B=b;
app.Pinf=pinf;
app.Tn= t(nn);
app.Pn=p(nn);


function Z=kexpint(Y,T,A)
%   inputs  Y(t) - row vector
%           T    - duration of Y
%           A    - exponential factor
%   Outputs Z    - row vector
N=length(Y)-1;
t1=(0:N)*T/N;
dt=t1(2)-t1(1);
Y0=Y(1);
y=Y-Y0;
% calculate integral of y using trapezoidal rule
ye=y.*exp(A*t1);
z=(cumtrapz(ye))*dt;
% add contribution from constant term
Z=z+Y0*(exp(A*t1)-1)/A;
end

function Prd = dias_int(Ps,Ts,a,b,Pinf,nn)
% inputs    Ps   - row vector of pressure for whole beat
%           Ts   - row vector of times for whole beat
%           a    - fitting parameter
%           b    - reciprocal of the diastolic time constant tau
%           Pinf - asymptote of diastolic pressure
%           nn   - index of notch
% output    Prd  - Pr during diastole

% calculate the integral using the trapezoidal rule
dt=Ts(2)-Ts(1);
pse=cumtrapz(Ps.*exp((a+b)*Ts))*dt;
prb = exp(-(a+b)*Ts).*(a*pse + Ps(1) - (b*Pinf/(a+b))) + b*Pinf/(a+b);
Prd=prb(nn:end);
end

function Pr = beat_int(Ps,Ts,a,b,Pinf)
% inputs    Ps   - row vector of pressure for whole beat
%           Ts   - row vector of times for whole beat
%           a    - fitting parameter
%           b    - reciprocal of the diastolic time constant tau
%           Pinf - asymptote of diastolic pressure
%           nn   - index of notch
% calculate the integral using the trapezoidal rule
dt=Ts(2)-Ts(1);
pse=cumtrapz(Ps.*exp((a+b)*Ts))*dt;
prb = exp(-(a+b)*Ts).*(a*pse + Ps(1) - (b*Pinf/(a+b))) + b*Pinf/(a+b);
Pr=prb;
end


%% Savitsky-Golay first derivative filter function
% dx=fsg721(x)
% 	7 point SavGol filter, 2nd order polynomial, 1st derivative
%	input 	x
%	output	dx
%	corrected for time shift
	
function dx=fsg721(x)
% 2nd order polynomial
C=[0.107143,0.071429,0.035714];

B1=zeros(1,7);
for i=1:3 
	B1(i)=C(i); 
end	
B1(4)=0.0;
for i=5:7
	B1(i)=-C(8-i);
end
A1=[1,0];
	
s=size(x,2);
dx=filter(B1,A1,x);
dx=[dx(7),dx(7),dx(7),dx(7:s),dx(s),dx(s),dx(s)];

end

        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.filename = app.FileNameEditField.Value;     % stores the filename used for exporting the data
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            path = uigetdir();                              % Allows the user to interactively select the directory to save the data
            if isempty(path) == 0                           % Checks to ensure a path has been selected
            name = fullfile(path,[app.filename '.txt']);    % Collates the filename and path
            fid = fopen(name, 'wt' );                       % Opens\creates the file in a write format
            t = app.time';                                  % Sets the time array to a vector
            yourCell = [t(:), app.P(:), app.Pr(:), app.Pdif(:), app.Prd(:)]; % Creates a matrix stroing the data to be saved           
            head = {'T(s)', 'P(Pa)','Pr(Pa)','Pwave(Pa)','exp'}; % Creates a header for the data
            fprintf(fid,'%s %s %s %s %s\n',head{:});        % Writes the header to the file
            fprintf(fid,'%f %f %f %f %f\n', yourCell');     % Writes the data to the file
            end
        end

        % Button pushed function: SaveGraphButton
        function SaveGraphButtonPushed(app, event)
            path = uigetdir();                              % Allows the user to interactively select the directory to save the data
            if isempty(path) == 0                           % Checks to ensure a path has been selected
            name = fullfile(path,[app.filename '.png']);    % Collates the filename and path
            exportgraphics(app.UIAxes,name, 'Resolution', 300) % Exports the graph to the specified file
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            % Confirms the user has saved all the data they need as it will be erased if they progress
            answer = questdlg('Have you saved what you need?', ...
	            'Check','Yes','No', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    check = 1;
                case 'No'                   
                    check = 2;                
            end

            % Passes the user back to the homepage
            if check == 1
                app.nextpage = Homepage(app);
                pause(0.5)
                delete(app)
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Passes the user to the previous page (PU adjust)
            app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
            app.D,app.Q,app.A2,app.ECG,app.time, app.Method);
            pause(0.5)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BeatDurationEditFieldLabel = uilabel(app.UIFigure);
            app.BeatDurationEditFieldLabel.Text = 'Beat Duration';
            app.BeatDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeatDurationEditField.ValueChangedFcn = createCallbackFcn(app, @BeatDurationEditFieldValueChanged, true);
            app.TimeofstartofdiastoleEditFieldLabel = uilabel(app.UIFigure);
            app.TimeofstartofdiastoleEditFieldLabel.Visible = 'off';
            app.TimeofstartofdiastoleEditFieldLabel.Text = 'Time of start of diastole';
            app.TimeofstartofdiastoleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeofstartofdiastoleEditField.ValueChangedFcn = createCallbackFcn(app, @TimeofstartofdiastoleEditFieldValueChanged, true);
            app.TimeofstartofdiastoleEditField.Visible = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SaveGraphButton = uibutton(app.UIFigure, 'push');
            app.SaveGraphButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphButtonPushed, true);
            app.SaveGraphButton.Text = 'Save Graph';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Windkessel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end