classdef clean < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CleanDataButton               matlab.ui.control.Button
        SaveandExitButton             matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        Tab2                          matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        Tab3                          matlab.ui.container.Tab
        UIAxes_3                      matlab.ui.control.UIAxes
        Tab4                          matlab.ui.container.Tab
        UIAxes_4                      matlab.ui.control.UIAxes
        Tab5                          matlab.ui.container.Tab
        UIAxes_5                      matlab.ui.control.UIAxes
        Tab6                          matlab.ui.container.Tab
        UIAxes_6                      matlab.ui.control.UIAxes
        WhatisthepolynomialorderEditField  matlab.ui.control.NumericEditField
        WhatisthepolynomialorderEditFieldLabel  matlab.ui.control.Label
        WhatisthewindowsizeEditField  matlab.ui.control.NumericEditField
        WhatisthewindowsizeEditFieldLabel  matlab.ui.control.Label
        WhichdatadoyouwishtocleanButtonGroup  matlab.ui.container.ButtonGroup
        Variable2CheckBox             matlab.ui.control.CheckBox
        Variable1CheckBox             matlab.ui.control.CheckBox
        ACheckBox                     matlab.ui.control.CheckBox
        DCheckBox                     matlab.ui.control.CheckBox
        UCheckBox                     matlab.ui.control.CheckBox
        QCheckBox                     matlab.ui.control.CheckBox
        PCheckBox                     matlab.ui.control.CheckBox
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage        % allows the next page to be selected
        Callingapp      % Stores the name of the previous app screen
        Output          % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)
        Method          % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)

        % Stores the variable arrays
        P
        U
        D
        Q
        A
        ECG
        time
       
        % variables to store which inputs the user wants to smooth
        P_smo = 0;
        U_smo = 0;
        D_smo = 0;
        Q_smo = 0;
        A_smo = 0;
        ECG_smo = 0;

        % temporary variables to store the smoothed outputs
        P_new
        U_new
        D_new
        Q_new
        A_new

        % variables to allow the use to specify the SavGol parameters
        window
        poly
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, Output, P, U, D, Q, A, ECG, time, Method)
            % positions the display
            movegui(app.UIFigure,"center"); 
            % saves the input arguments as variables
            app.Callingapp = Caller;
            app.Output = Output;
            app.P = P;
            app.U = U;
            app.D = D;
            app.Q = Q;
            app.A = A;
            app.ECG = ECG;
            app.time = time;
            app.Method = Method;
        end

        % Value changed function: PCheckBox
        function PCheckBoxValueChanged(app, event)
            % Stores if the user wants to smooth P
            value = app.PCheckBox.Value;
            if value == 1
                app.P_smo = 1;
            else
                app.P_smo = 0;
            end                
        end

        % Value changed function: QCheckBox
        function QCheckBoxValueChanged(app, event)
            % Stores if the user wants to smooth Q
            value = app.QCheckBox.Value;
            if value == 1
                app.Q_smo = 1;
            else
                app.Q_smo = 0;
            end                       
        end

        % Value changed function: UCheckBox
        function UCheckBoxValueChanged(app, event)
            % Stores if the user wants to smooth U
            value = app.UCheckBox.Value;
            if value == 1
                app.U_smo = 1;
            else
                app.U_smo = 0;
            end                       
        end

        % Value changed function: DCheckBox
        function DCheckBoxValueChanged(app, event)
            % Stores if the user wants to smooth D
            value = app.DCheckBox.Value;
            if value == 1
                app.D_smo = 1;
            else
                app.D_smo = 0;
            end                     
        end

        % Value changed function: ACheckBox
        function ACheckBoxValueChanged(app, event)
            % Stores if the user wants to smooth A
            value = app.ACheckBox.Value;
            if value == 1
                app.A_smo = 1;
            else
                app.A_smo = 0;
            end                       
        end

        % Value changed function: WhatisthewindowsizeEditField
        function WhatisthewindowsizeEditFieldValueChanged(app, event)
            % Allows the user to specify the window size for the
            % savgol filter
            value = app.WhatisthewindowsizeEditField.Value;
            app.window = value;
        end

        % Value changed function: WhatisthepolynomialorderEditField
        function WhatisthepolynomialorderEditFieldValueChanged(app, event)
            % Allows the user to specify the polynomial order for the
            % savgol filter
            value = app.WhatisthepolynomialorderEditField.Value;
            app.poly = value;
        end

        % Button pushed function: CleanDataButton
        function CleanDataButtonPushed(app, event)
            count = 1;                  % Used to track which Axis is to be plotted on
            % Detects if the user wants to smooth P and displays the ouput
            % in a plot
            if app.P_smo == 1
                app.P_new = sgolayfilt(app.P,app.poly,app.window); % filters the data using the inputed windo and poly numbers
                if count == 1
                    plot(app.UIAxes, app.time, app.P_new, 'x-');                    
                    ylabel(app.UIAxes, 'P (Pa)')
                    xlabel(app.UIAxes, 'time (s)')                    
                    count = count + 1;
                end
            end
            % Detects if the user wants to smooth U and displays the ouput
            % in a plot
            if app.U_smo == 1
                app.U_new = sgolayfilt(app.U,app.poly,app.window);
                if count == 1
                    plot(app.UIAxes, app.time, app.U_new, 'x-');                   
                    ylabel(app.UIAxes, 'U (m/s)')
                    xlabel(app.UIAxes, 'time (s)')
                else
                    plot(app.UIAxes_2, app.time, app.U_new, 'x-');                   
                    ylabel(app.UIAxes_2, 'U (m/s)')
                    xlabel(app.UIAxes_2, 'time (s)')
                end
                count = count +1;
            end
            % Detects if the user wants to smooth D and displays the ouput
            % in a plot
            if app.D_smo == 1
                app.D_new = sgolayfilt(app.D,app.poly,app.window);
                if count == 1
                    plot(app.UIAxes, app.time, app.D_new, 'x-');                    
                    ylabel(app.UIAxes, 'D (m)')
                    xlabel(app.UIAxes, 'time (s)')
                elseif count == 2
                    plot(app.UIAxes_2, app.time, app.D_new, 'x-');                  
                    ylabel(app.UIAxes_2, 'D (m)')
                    xlabel(app.UIAxes_2, 'time (s)')
                else
                    plot(app.UIAxes_3, app.time, app.D_new, 'x-');                    
                    ylabel(app.UIAxes_3, 'D (m)')
                    xlabel(app.UIAxes_3, 'time (s)')
                end
                count = count + 1;
            end
            % Detects if the user wants to smooth Q and displays the ouput
            % in a plot
            if app.Q_smo == 1
                app.Q_new = sgolayfilt(app.Q,app.poly,app.window);
                if count == 1
                    plot(app.UIAxes, app.time, app.Q_new, 'x-');                    
                    ylabel(app.UIAxes, 'Q (m^3/s)')
                    xlabel(app.UIAxes, 'time (s)')
                elseif count == 2
                    plot(app.UIAxes_2, app.time, app.Q_new, 'x-');                   
                    ylabel(app.UIAxes_2, 'Q (m^3/s)')
                    xlabel(app.UIAxes_2, 'time (s)')
                elseif count == 3
                    plot(app.UIAxes_3, app.time, app.Q_new, 'x-');                   
                    ylabel(app.UIAxes_3, 'Q (m^3/s)')
                    xlabel(app.UIAxes_3, 'time (s)')
                else
                    plot(app.UIAxes_4, app.time, app.Q_new, 'x-');                 
                    ylabel(app.UIAxes_4, 'Q (m^3/s)')
                    xlabel(app.UIAxes_4, 'time (s)')
                end
                count = count + 1;
            end
            % Detects if the user wants to smooth A and displays the ouput
            % in a plot
            if app.A_smo == 1
                app.A_new = sgolayfilt(app.A,app.poly,app.window);
                if count == 1
                    plot(app.UIAxes, app.time, app.A_new, 'x-');                 
                    ylabel(app.UIAxes, 'A (m^2)')
                    xlabel(app.UIAxes, 'time (s)')
                elseif count == 2
                    plot(app.UIAxes_2, app.time, app.A_new, 'x-');                    
                    ylabel(app.UIAxes_2, 'A (m^2)')
                    xlabel(app.UIAxes_2, 'time (s)')
                elseif count == 3
                    plot(app.UIAxes_3, app.time, app.A_new, 'x-');                  
                    ylabel(app.UIAxes_3, 'A (m^2)')
                    xlabel(app.UIAxes_3, 'time (s)')
                elseif count == 4
                    plot(app.UIAxes_4, app.time, app.A_new, 'x-');                  
                    ylabel(app.UIAxes_4, 'A (m^2)')
                    xlabel(app.UIAxes_4, 'time (s)')
                else
                    plot(app.UIAxes_5, app.time, app.A_new, 'x-');                   
                    ylabel(app.UIAxes_5, 'A (m^2)')
                    xlabel(app.UIAxes_5, 'time (s)')
                end
            end
        end

        % Button pushed function: SaveandExitButton
        function SaveandExitButtonPushed(app, event)
            % Replaces the original array with the smoothed data 
            if isempty(app.P_new) == 0
                app.P = app.P_new;
            end
            if isempty(app.U_new) == 0
                app.U = app.U_new;
            end
            if isempty(app.D_new) == 0
                app.D = app.D_new;
            end
            if isempty(app.Q_new) == 0
                app.Q = app.Q_new;
            end
            if isempty(app.A_new) == 0
                app.A = app.A_new;
            end
            % This calulates U if the user inputs Q and A
            if isempty(app.Q_new) == 0 && isempty(app.A_new) == 0
                app.U = app.Q./app.A;
            end            
            % moves to the next display
            % Question to be asked if the user has multiple ECG peaks
            prompt = {'Input QRS peak to analyse from'};
            dlgtitle = 'QRS peak';
            dims = [1 35];
            definput = {'1'};
            % Checks if the user is happy with their new graphs
            answer = questdlg('Are you happy with these graphs?', ...
	            'Check','Yes','No', 'No');
            % Handle response
            switch answer
                case 'Yes'
                    check = 1;
                case 'No'                   
                    check = 2;                
            end           
            if app.Method == 1 || 3
                % if either Invasive / Windkessel technique was selected
                if isempty(app.ECG) == 0                            % Checks if an ECG exists
                    % if yes
                    peak = inputdlg(prompt,dlgtitle,dims,definput); % Displays the message created above
                    index=detectR3(app.time,app.ECG,app.P,200);     % Calls a function to detect the the QRS peaks and stores them in an array
                    x= str2double(peak);                            % converts the user input from string to double               
                    y=x+1;                                          % sets the seconf ECG point as the one after the user input
                    P_temp=app.P(index(x):index(y));                % Selects the range of P values based on the ECG peaks selected by the user
                    U_temp=app.U(index(x):index(y));                % Selects the range of U values based on the ECG peaks selected by the user
                    t_temp=app.time(index(x):index(y));             % Selects the range of t values based on the ECG peaks selected by the user
                else
                    % if no, then vartiables don't change but stores in a
                    % temprary variable
                    P_temp = app.P;
                    U_temp = app.U;
                    t_temp = app.time;
                end
                    % Overwrites the exsisting variables with the new
                    % arrays 
                    app.P = P_temp;
                    app.U = U_temp;
                    t1 = t_temp - t_temp(1);    % normalises the time vector so it sstarts at zero
                    app.time = t1;              % Overwrites with the normalised time array                
                if check == 1                   % Checks if the user was happy with the graphs
                    % if yes it moves to the waveform alignment page
                    % (PUadjust)
                    app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG,app.time, app.Method);
                    pause(0.5)
                    delete(app)  
                end
            elseif app.Method == 2
                % if non-invasive method selected
                if isempty(app.ECG) == 0
                    % same as for the invasive / windkessel branch but for
                    % the non-invasive variables (see  above for comments)
                    peak = inputdlg(prompt,dlgtitle,dims,definput);
                    index=detectR3(app.time,app.ECG,app.lnD,200);
                    x= str2double(peak);
                    y=x+1;
                    lnD_temp=app.lnD(index(x):index(y));
                    U_temp=app.U(index(x):index(y));
                    t_temp=app.time(index(x):index(y));
                else
                    lnD_temp = app.lnD;
                    U_temp = app.U;
                    t_temp = app.time;
                end                    
                    app.lnD = lnD_temp;
                    app.U = U_temp;
                    t1 = t_temp - t_temp(1);
                    app.time = t1;                
                if check == 1
                    app.nextpage = PUadjust(app,app.Output,app.P,app.U, ...
                    app.D,app.Q,app.A,app.ECG,app.time, app.Method);
                    pause(0.5)
                    delete(app)  
                end
            end
% Function that detect R-wive of input signal
% using differentiation and then a new threshold
function [r_wave]=detectR3(time,signal1,signal2,threshold)

t=length(time);         % stores the length of the time array
s=length(signal1);      % stores the length of the signal array (ECG signal)

grad=diff(signal1);     % calculates the gradient

a1=1;                   % Variable to keep track of position in while loops 
i1=1;                   % Variable to keep track of position in while loops 
while i1<s              % tests whether the gradient of every index point point is smaller than the threshold value 
  if grad(i1)<-threshold
    q1=i1;              % if yes it stores the index
    while q1>0          
      if grad(q1)>0     % tests whether the gradient of the differential is postiive (detecting R peak)
        r_wave(a1)=q1+1; % stores the index value of the R peak
        a1=a1+1;
        q1=0;           % stops the while loop if an R peak detected
      end
      q1=q1-1;          % works backwards to try and find the r peak
    end
    while i1<s && grad(i1)<0 % While no R peak detected ( as gradient is negative) move to the next index point
      i1=i1+1;
    end
  end
  i1=i1+1;
end

in1=find(t==t(1));      % gets the first time value (typically 0)
in2=length(signal2);    % gets the length of the second signal (P or lnD)
[r_wave]=[in1 r_wave in2]; % stores the indexs of all of the R peaks

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhichdatadoyouwishtocleanButtonGroup = uibuttongroup(app.UIFigure);
            app.WhichdatadoyouwishtocleanButtonGroup.Title = 'Which data do you wish to clean?';
            app.PCheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.PCheckBox.ValueChangedFcn = createCallbackFcn(app, @PCheckBoxValueChanged, true);
            app.PCheckBox.Text = 'P';
            app.QCheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.QCheckBox.ValueChangedFcn = createCallbackFcn(app, @QCheckBoxValueChanged, true);
            app.QCheckBox.Text = 'Q';
            app.UCheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.UCheckBox.ValueChangedFcn = createCallbackFcn(app, @UCheckBoxValueChanged, true);
            app.UCheckBox.Text = 'U';
            app.DCheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.DCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCheckBoxValueChanged, true);
            app.DCheckBox.Text = 'D';
            app.ACheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.ACheckBox.ValueChangedFcn = createCallbackFcn(app, @ACheckBoxValueChanged, true);
            app.ACheckBox.Text = 'A';
            app.Variable1CheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.Variable1CheckBox.Visible = 'off';
            app.Variable1CheckBox.Text = 'Variable 1';
            app.Variable2CheckBox = uicheckbox(app.WhichdatadoyouwishtocleanButtonGroup);
            app.Variable2CheckBox.Visible = 'off';
            app.Variable2CheckBox.Text = 'Variable 2';
            app.WhatisthewindowsizeEditFieldLabel = uilabel(app.UIFigure);
            app.WhatisthewindowsizeEditFieldLabel.Text = 'What is the window size?';
            app.WhatisthewindowsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WhatisthewindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WhatisthewindowsizeEditFieldValueChanged, true);
            app.WhatisthepolynomialorderEditFieldLabel = uilabel(app.UIFigure);
            app.WhatisthepolynomialorderEditFieldLabel.Text = 'What is the polynomial order?';
            app.WhatisthepolynomialorderEditField = uieditfield(app.UIFigure, 'numeric');
            app.WhatisthepolynomialorderEditField.ValueChangedFcn = createCallbackFcn(app, @WhatisthepolynomialorderEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes_2 = uiaxes(app.Tab2);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_3 = uiaxes(app.Tab3);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_4 = uiaxes(app.Tab4);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes_5 = uiaxes(app.Tab5);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes_6 = uiaxes(app.Tab6);
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.SaveandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExitButtonPushed, true);
            app.SaveandExitButton.Text = 'Save and Exit';
            app.CleanDataButton = uibutton(app.UIFigure, 'push');
            app.CleanDataButton.ButtonPushedFcn = createCallbackFcn(app, @CleanDataButtonPushed, true);
            app.CleanDataButton.Text = 'Clean Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clean(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end