classdef whichinputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TimeDropDown       matlab.ui.control.DropDown
        TimeDropDownLabel  matlab.ui.control.Label
        AreallinputscontainedwithinonefileButtonGroup  matlab.ui.container.ButtonGroup
        NoButton           matlab.ui.control.RadioButton
        YesButton          matlab.ui.control.RadioButton
        ECGDropDown        matlab.ui.control.DropDown
        ECGDropDownLabel   matlab.ui.control.Label
        A_Unit             matlab.ui.control.DropDown
        NextButton         matlab.ui.control.Button
        BackButton         matlab.ui.control.Button
        Q_Unit             matlab.ui.control.DropDown
        D_Unit             matlab.ui.control.DropDown
        U_Unit             matlab.ui.control.DropDown
        P_Unit             matlab.ui.control.DropDown
        TextArea2_2        matlab.ui.control.TextArea
        TextArea2_2Label   matlab.ui.control.Label
        QDropDownLabel     matlab.ui.control.Label
        QDropDown          matlab.ui.control.DropDown
        DDropDownLabel     matlab.ui.control.Label
        DDropDown          matlab.ui.control.DropDown
        UDropDownLabel     matlab.ui.control.Label
        UDropDown          matlab.ui.control.DropDown
        PDropDown          matlab.ui.control.DropDown
        PDropDownLabel     matlab.ui.control.Label
        TextArea2          matlab.ui.control.TextArea
        TextArea2Label     matlab.ui.control.Label
        ADropDown          matlab.ui.control.DropDown
        ADropDownLabel     matlab.ui.control.Label
    end

    % Creates a list of variables global to the app page
    properties (Access = private)
        nextpage            % allows the next page to be selected
        CallingApp          % Stores the name of the previous app screen
        Method              % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)
        Output              % (no longer needed within the code but heavily embedded so not removed but is empty so has no effect)

        % These store which variables the user has to upload and what type
        % of file they're stored within
        % Sets it so initialy no inouts are selected
        Pvalue = "No";      
        Uvalue = "No";
        Dvalue = "No";
        Qvalue = "No";
        Avalue = "No";
        ECGvalue = "No";
        timevalue = "No";

        % These variables store the units of the inputs
        PvalUnit
        UvalUnit
        DvalUnit
        QvalUnit
        AvalUnit

        Onefile             % Stores whether the data is containe dwithin one file or seperate files
        Othersignal         % Stores whether the user wishes to add their own inputs
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, Output, Method)
            % saves the input arguments as variables within the app
            movegui(app.UIFigure,"center"); % Centers the GUI
            app.CallingApp = caller; % stores the name of the app which called the page
            app.Output = Output; % No longer needed
            app.Method = Method; % stores which techniques to be used ( 1 = Invasive/ 2 = Non-Invasive/ 3 = Windkessel)      
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Allows the user to progress to the next display
            % Passes all of the required variables to the next page
            app.nextpage = Inputpage(app,app.Output,app.Pvalue,app.Uvalue, ...
                app.Dvalue,app.Qvalue,app.Avalue,app.ECGvalue,app.PvalUnit, ...
                app.UvalUnit,app.DvalUnit,app.QvalUnit,app.AvalUnit,app.Onefile,app.Othersignal, app.timevalue, app.Method);
            pause(0.5)  % Pauses while the next screen initiates
            delete(app) % Closes the current screen   
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Takes te user to the previous display
            app.nextpage = Homepage(app); % Doesn't pass any variables
            pause(0.5)  % Pauses while the next screen initiates
            delete(app) % Closes the current screen   
        end

        % Value changed function: PDropDown
        function PDropDownValueChanged(app, event)
            app.Pvalue = app.PDropDown.Value;   % Stores the input file type for P
        end

        % Value changed function: UDropDown
        function UDropDownValueChanged(app, event)
            app.Uvalue = app.UDropDown.Value;   % Stores the input file type for U
        end

        % Value changed function: DDropDown
        function DDropDownValueChanged(app, event)
            app.Dvalue = app.DDropDown.Value;   % Stores the input file type for D
        end

        % Value changed function: QDropDown
        function QDropDownValueChanged(app, event)
            app.Qvalue = app.QDropDown.Value;   % Stores the input file type for Q
        end

        % Value changed function: ADropDown
        function ADropDownValueChanged(app, event)
            app.Avalue = app.ADropDown.Value;   % Stores the input file type for A
        end

        % Value changed function: ECGDropDown
        function ECGDropDownValueChanged(app, event)
            app.ECGvalue = app.ECGDropDown.Value; % Stores the input file type for ECG
        end

        % Value changed function: P_Unit
        function P_UnitValueChanged(app, event)
            app.PvalUnit = app.P_Unit.Value;    % Stores the unit for P
        end

        % Value changed function: U_Unit
        function U_UnitValueChanged(app, event)
            app.UvalUnit = app.U_Unit.Value;    % Stores the unit for U
        end

        % Value changed function: D_Unit
        function D_UnitValueChanged(app, event)
           app.DvalUnit = app.D_Unit.Value;     % Stores the unit for D
        end

        % Value changed function: Q_Unit
        function Q_UnitValueChanged(app, event)
            app.QvalUnit = app.Q_Unit.Value;    % Stores the unit for Q
        end

        % Value changed function: A_Unit
        function A_UnitValueChanged(app, event)
            app.AvalUnit = app.A_Unit.Value;    % Stores the unit for A      
        end

        % Selection changed function: 
        % AreallinputscontainedwithinonefileButtonGroup
        function AreallinputscontainedwithinonefileButtonGroupSelectionChanged(app, event)
 % Detects the position of the toggle switch and saves the value to the
 % variable Onefile (for if all of the users input data is stored in one
 % file)
 switch app.AreallinputscontainedwithinonefileButtonGroup.SelectedObject.Text
     case 'Yes'
         app.Onefile = 1;
     case 'No'
         app.Onefile = 0;
     otherwise
         app.Onefile = 0;
 end
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            app.timevalue = app.TimeDropDown.Value; % Stores the input file type for time
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADropDownLabel = uilabel(app.UIFigure);
            app.ADropDownLabel.Text = 'A';
            app.ADropDown = uidropdown(app.UIFigure);
            app.ADropDown.Items = {'No', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.ADropDown.ValueChangedFcn = createCallbackFcn(app, @ADropDownValueChanged, true);
            app.ADropDown.Value = 'No';
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2.Value = {'What do you have'};
            app.PDropDownLabel = uilabel(app.UIFigure);
            app.PDropDownLabel.Text = 'P';
            app.PDropDown = uidropdown(app.UIFigure);
            app.PDropDown.Items = {'No', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.PDropDown.ValueChangedFcn = createCallbackFcn(app, @PDropDownValueChanged, true);
            app.PDropDown.Value = 'No';
            app.UDropDown = uidropdown(app.UIFigure);
            app.UDropDown.Items = {'No', 'Image', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.UDropDown.ValueChangedFcn = createCallbackFcn(app, @UDropDownValueChanged, true);
            app.UDropDown.Value = 'No';
            app.UDropDownLabel = uilabel(app.UIFigure);
            app.UDropDownLabel.Text = 'U';
            app.DDropDown = uidropdown(app.UIFigure);
            app.DDropDown.Items = {'No', 'Image', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.DDropDown.ValueChangedFcn = createCallbackFcn(app, @DDropDownValueChanged, true);
            app.DDropDown.Value = 'No';
            app.DDropDownLabel = uilabel(app.UIFigure);
            app.DDropDownLabel.Text = 'D';
            app.QDropDown = uidropdown(app.UIFigure);
            app.QDropDown.Items = {'No', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.QDropDown.ValueChangedFcn = createCallbackFcn(app, @QDropDownValueChanged, true);
            app.QDropDown.Value = 'No';
            app.QDropDownLabel = uilabel(app.UIFigure);
            app.QDropDownLabel.Text = 'Q';
            app.TextArea2_2Label = uilabel(app.UIFigure);
            app.TextArea2_2Label.Text = 'Text Area2';
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_2.Value = {'Unit'};
            app.P_Unit = uidropdown(app.UIFigure);
            app.P_Unit.Items = {'-', 'mm/Hg', 'Pa', 'kPa'};
            app.P_Unit.ValueChangedFcn = createCallbackFcn(app, @P_UnitValueChanged, true);
            app.P_Unit.Value = '-';
            app.U_Unit = uidropdown(app.UIFigure);
            app.U_Unit.Items = {'-', 'm/s', 'mm/s'};
            app.U_Unit.ValueChangedFcn = createCallbackFcn(app, @U_UnitValueChanged, true);
            app.U_Unit.Value = '-';
            app.D_Unit = uidropdown(app.UIFigure);
            app.D_Unit.Items = {'-', 'mm', 'cm'};
            app.D_Unit.ValueChangedFcn = createCallbackFcn(app, @D_UnitValueChanged, true);
            app.D_Unit.Value = '-';
            app.Q_Unit = uidropdown(app.UIFigure);
            app.Q_Unit.Items = {'-', 'l/min', 'm^3/s', 'ml/min'};
            app.Q_Unit.ValueChangedFcn = createCallbackFcn(app, @Q_UnitValueChanged, true);
            app.Q_Unit.Value = '-';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.A_Unit = uidropdown(app.UIFigure);
            app.A_Unit.Items = {'-', 'mm^2', 'cm^2'};
            app.A_Unit.ValueChangedFcn = createCallbackFcn(app, @A_UnitValueChanged, true);
            app.A_Unit.Value = '-';
            app.ECGDropDownLabel = uilabel(app.UIFigure);
            app.ECGDropDownLabel.Text = 'ECG';
            app.ECGDropDown = uidropdown(app.UIFigure);
            app.ECGDropDown.Items = {'No', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.ECGDropDown.ValueChangedFcn = createCallbackFcn(app, @ECGDropDownValueChanged, true);
            app.ECGDropDown.Value = 'No';
            app.AreallinputscontainedwithinonefileButtonGroup = uibuttongroup(app.UIFigure);
            app.AreallinputscontainedwithinonefileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AreallinputscontainedwithinonefileButtonGroupSelectionChanged, true);
            app.AreallinputscontainedwithinonefileButtonGroup.Title = 'Are all inputs contained within one file?';
            app.YesButton = uiradiobutton(app.AreallinputscontainedwithinonefileButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.AreallinputscontainedwithinonefileButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.TimeDropDownLabel = uilabel(app.UIFigure);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.UIFigure);
            app.TimeDropDown.Items = {'No', '.txt', '.csv', '.asc', '.xcel', 'matlab vector'};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whichinputs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end